% description: % CardioMark: A user-friendly tool for manually annotating ECG recordings.
% % Enables precise identification of QRS onset, QRS offset, and T-wave offset
% % for calculating QRS duration (QRSd) and QT interval. Features a GUI with
% % intuitive tools for waveform labeling, slur/notch detection, and QRS Morphology
% % Supports multiple ECG file formats and exports annotations
% % for seamless integration with AI systems or external review.
% %% Citation:
% % S. Abdel-Rahman et al., "Faster R-CNN approach for estimating global QRS duration
% % in electrocardiograms with a limited quantity of annotated data," Comput. Biol. Med.,
% % vol. 192, p. 110200, 2025. https://doi.org/10.1016/j.compbiomed.2025.110200
classdef CardioMark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardioMarkV10UIFigure         matlab.ui.Figure
        AnnotationExportPanel         matlab.ui.container.Panel
        MATButton                     matlab.ui.control.Button
        JSONButton                    matlab.ui.control.Button
        CSVButton                     matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        QRSMorphologyPanel            matlab.ui.container.Panel
        Observer2QRSMorphologyLabel   matlab.ui.control.Label
        Observer1QRSMorphologyLabel   matlab.ui.control.Label
        QRSMorphologyDropDown         matlab.ui.control.DropDown
        CommentsPanel                 matlab.ui.container.Panel
        Observer2CommentsLabel        matlab.ui.control.Label
        Observer1CommentsLabel        matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        MeasurmentsmsPanel            matlab.ui.container.Panel
        QTLbl                         matlab.ui.control.Label
        ToffLbl                       matlab.ui.control.Label
        QRSdLbl                       matlab.ui.control.Label
        QRSoffLbl                     matlab.ui.control.Label
        QRSOnLbl                      matlab.ui.control.Label
        QTLabel                       matlab.ui.control.Label
        ToffLabel                     matlab.ui.control.Label
        QRSdLabel_2                   matlab.ui.control.Label
        QRSoffLabel                   matlab.ui.control.Label
        QRSonLabel                    matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SuperimposeTab                matlab.ui.container.Tab
        LoadFullLeadsCheckBox         matlab.ui.control.CheckBox
        AutoRPeakCheckBox             matlab.ui.control.CheckBox
        ObserverDifferencesinmsifAnyPanel  matlab.ui.container.Panel
        Observer2Label                matlab.ui.control.Label
        Observer1Label                matlab.ui.control.Label
        Observer2QT                   matlab.ui.control.Label
        Observer2Toff                 matlab.ui.control.Label
        Observer2QRSd                 matlab.ui.control.Label
        Observer2Off                  matlab.ui.control.Label
        Observer2On                   matlab.ui.control.Label
        Observer1QT                   matlab.ui.control.Label
        Observer1Toff                 matlab.ui.control.Label
        Observer1QRSd                 matlab.ui.control.Label
        Observer1Off                  matlab.ui.control.Label
        Observer1On                   matlab.ui.control.Label
        QTLabel_2                     matlab.ui.control.Label
        ToffLabel_2                   matlab.ui.control.Label
        QRSdLabel_3                   matlab.ui.control.Label
        QRSoffLabel_2                 matlab.ui.control.Label
        QRSonLabel_2                  matlab.ui.control.Label
        KeyCommandLabel_2             matlab.ui.control.Label
        Gain2XSliderLabel             matlab.ui.control.Label
        Gain2XSlider                  matlab.ui.control.Slider
        AutoR_PeaksButton             matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        WaitImage                     matlab.ui.control.Image
        LegendButton                  matlab.ui.control.StateButton
        KeyCommandLabel               matlab.ui.control.Label
        TwinLeadDropDown              matlab.ui.control.DropDown
        SecondaryAxisPlotLabel        matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        LimitEditField                matlab.ui.control.NumericEditField
        QTButton                      matlab.ui.control.StateButton
        LeadsSelectListBox            matlab.ui.control.ListBox
        QRSButton                     matlab.ui.control.StateButton
        BoundarySelectionButtonGroup  matlab.ui.container.ButtonGroup
        GoRightButton                 matlab.ui.control.Button
        GoLeftButton                  matlab.ui.control.Button
        TOffsetButton                 matlab.ui.control.RadioButton
        QRSOffsetButton               matlab.ui.control.RadioButton
        QRSOnsetButton                matlab.ui.control.RadioButton
        LeadsListBoxLabel             matlab.ui.control.Label
        ECGPlot_2                     matlab.ui.control.UIAxes
        ECGPlot                       matlab.ui.control.UIAxes
        LimbLeadsTab                  matlab.ui.container.Tab
        NextButton_2                  matlab.ui.control.Button
        aVF                           MedianBeat_Config
        aVL                           MedianBeat_Config
        aVR                           MedianBeat_Config
        III                           MedianBeat_Config
        II                            MedianBeat_Config
        I                             MedianBeat_Config
        ChestLeadsTab                 matlab.ui.container.Tab
        NextButton_3                  matlab.ui.control.Button
        V6                            MedianBeat_Config
        V5                            MedianBeat_Config
        V4                            MedianBeat_Config
        V3                            MedianBeat_Config
        V2                            MedianBeat_Config
        V1                            MedianBeat_Config
        FullLeadsTab                  matlab.ui.container.Tab
        LeadsforPlotPanel             matlab.ui.container.Panel
        PlotButton                    matlab.ui.control.Button
        V6Button                      matlab.ui.control.StateButton
        V5Button                      matlab.ui.control.StateButton
        V4Button                      matlab.ui.control.StateButton
        V3Button                      matlab.ui.control.StateButton
        V2Button                      matlab.ui.control.StateButton
        aVFButton                     matlab.ui.control.StateButton
        aVLButton                     matlab.ui.control.StateButton
        aVRButton                     matlab.ui.control.StateButton
        IIIButton                     matlab.ui.control.StateButton
        IIButton                      matlab.ui.control.StateButton
        V1Button                      matlab.ui.control.StateButton
        IButton                       matlab.ui.control.StateButton
        ECGLeadPlot                   matlab.ui.control.UIAxes
        StatusLabel                   matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        ECGFileSelector               ECGFileSelector
        SettingsPanel                 matlab.ui.container.Panel
        UseRef2CheckBox               matlab.ui.control.CheckBox
        UseRef1CheckBox               matlab.ui.control.CheckBox
        SaveOnlyCompleteDataCheckBox  matlab.ui.control.CheckBox
        UseonsetoffsetfromLabel       matlab.ui.control.Label
    end


    %% Comments
    %[11-06-2025]
    % CardioMark: A user-friendly tool for manually annotating ECG recordings.
    % Enables precise identification of QRS onset, QRS offset, and T-wave offset
    % for calculating QRS duration (QRSd) and QT interval. Features a GUI with
    % intuitive tools for waveform labeling, slur/notch detection, and heartbeat
    % classification. Supports multiple ECG file formats and exports annotations
    % for seamless integration with AI systems or external review.
    %% Citation:
    % S. Abdel-Rahman et al., "Faster R-CNN approach for estimating global QRS duration
    % in electrocardiograms with a limited quantity of annotated data," Comput. Biol. Med.,
    % vol. 192, p. 110200, 2025. https://doi.org/10.1016/j.compbiomed.2025.110200


    properties (Access = public)
        QRS ; ECGFileName ; LeadId=0 ; LeadTxt='Limb' ;
        QRSOn=1 ; QRSOff=2 ; QTOff=2 ;  QRSOnLine ;  QRSOFFLine ; QTOffLine ; QRSd=0; QTd=0;
        TwinQRSOnLine ;  TwinQRSOFFLine ; TwinQTOffLine ;
        KeyCommand=0;FolderPath
        OrginalLimit;OrginalLimitV=[0,0];
    end

    properties (Access = private)
        PlotIdxes=[1]; % Description
    end

    methods (Access = private)
        function results = FillMiniSummary(app)
            % refresh "Measurments ms" panel
            app.QRSOnLbl.Text=num2str(app.QRSOn);
            app.QRSoffLbl.Text=num2str(app.QRSOff);
            app.QRSd=app.QRSOff- app.QRSOn;
            app.QRSdLbl.Text=num2str(app.QRSd);
            app.QRSd=app.QRSOff- app.QRSOn;
            app.ToffLbl.Text=num2str(app.QTOff);
            app.QTd=app.QTOff- app.QRSOn;
            app.QTLbl.Text=num2str(app.QTd);

            % Filling Observer 1 and 2 diffferences if ANY!
            if ~isempty(app.ECGFileSelector.Ref1Record)
                Ref1QRSon=app.ECGFileSelector.Ref1Record.Onset;
                Ref1QRSd=app.ECGFileSelector.Ref1Record.QRSd;
                Ref1QT= app.ECGFileSelector.Ref1Record.QTd;
                Ref1QRSoff=app.ECGFileSelector.Ref1Record.Onset +   Ref1QRSd;
                Ref1Toff=app.ECGFileSelector.Ref1Record.Onset   +  Ref1QT;


            else
                Ref1QRSon=0;    Ref1QRSd=0; Ref1QT= 0;   Ref1QRSoff=0;  Ref1Toff=0;
            end

            if ~isempty(app.ECGFileSelector.Ref2Record)
                Ref2QRSon=app.ECGFileSelector.Ref2Record.Onset;
                Ref2QRSd=app.ECGFileSelector.Ref2Record.QRSd;
                Ref2QT= app.ECGFileSelector.Ref2Record.QTd;
                Ref2QRSoff=app.ECGFileSelector.Ref2Record.Onset +   Ref2QRSd;
                Ref2Toff=app.ECGFileSelector.Ref2Record.Onset   +  Ref2QT;
            else
                Ref2QRSon=0;    Ref2QRSd=0; Ref2QT= 0;    Ref2QRSoff=0;   Ref2Toff=0;

            end

            if Ref1QRSon >10 && app.QRSd >10 && app.QTd >10
                app.Observer1On.Text= num2str(app.QRSOn-Ref1QRSon);
                app.Observer1Off.Text= num2str(app.QRSOff-Ref1QRSoff);
                app.Observer1QRSd.Text= num2str(app.QRSd-Ref1QRSd);
                app.Observer1Toff.Text= num2str(app.QTOff-Ref1Toff);
                app.Observer1QT.Text= num2str(app.QTd-Ref1QT);

            else
                app.Observer1On.Text='-'; app.Observer1Off.Text= '-'; app.Observer1QRSd.Text='-'; app.Observer1Toff.Text='-'; app.Observer1QT.Text= '-';
            end

            if Ref2QRSon >10 && app.QRSd >10 && app.QTd >10
                app.Observer2On.Text= num2str(app.QRSOn-Ref2QRSon);
                app.Observer2Off.Text= num2str(app.QRSOff-Ref2QRSoff);
                app.Observer2QRSd.Text= num2str(app.QRSd-Ref2QRSd);
                app.Observer2Toff.Text= num2str(app.QTOff-Ref2Toff);
                app.Observer2QT.Text= num2str(app.QTd-Ref2QT);

            else
                app.Observer2On.Text='-'; app.Observer2Off.Text= '-'; app.Observer2QRSd.Text='-'; app.Observer2Toff.Text='-'; app.Observer2QT.Text= '-';
            end


            results='OK FillMiniSummary';


        end

        function results = UseREFSets(app,UseIt,RefOnset,RefQRSd,RefTOd)
            % function to manage observers annotations and make it more modular
            if UseIt>0
                RefOffset=RefOnset+RefQRSd ;
                RefTOffset=RefOnset+RefTOd ;
                if RefOnset>3
                    app.QRSOn =RefOnset;
                    app.QRSOnLine.Value=app.QRSOn;
                end
                if RefOffset>3
                    app.QRSOff =RefOffset;
                    app.QRSOFFLine.Value=app.QRSOff;
                end

                if RefTOffset>3
                    app.QTOff =RefTOffset;
                    app.QTOffLine.Value=app.QTOff;
                end
                FillMiniSummary(app);
            end
            results='Done-UseREFSets';
        end
    end

    methods (Access = public)
        function results = ExtraFunction(app) % % %
            results=app.QRSOn;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup initialisation
            clc ;
            appFile = mfilename('fullpath') ; appDir = fileparts(appFile) ;
            cd(appDir) ;
        end

        % Button down function: ECGPlot
        function ECGPlotButtonDown(app, event)
            % selecting boundaries on the main axis plot.
            coordinates = event.IntersectionPoint(1:2) ;

            if (event.Button)==1 && app.KeyCommand ==0
                app.QRSOn =round(coordinates(1)/2,0)*2;
                app.QRSOnLine.Value=app.QRSOn;
                app.TwinQRSOnLine.Value=app.QRSOn;
            elseif (event.Button)==3 || ( (event.Button)==1 && app.KeyCommand ==1)
                if app.QRSOnLine.Value< coordinates(1)
                    app.QRSOff =round(coordinates(1)/2,0)*2;
                    app.QRSOFFLine.Value= app.QRSOff;
                    app.TwinQRSOFFLine.Value= app.QRSOff; % if app.ShowTwin==1
                    % app.QRSoffLbl.Text=num2str( app.QRSOff);
                end
            elseif (event.Button)==2 || ( (event.Button)==1 && app.KeyCommand ==2)
                app.QTOff =round(coordinates(1)/2,0)*2;
                app.QTOffLine.Value= app.QTOff ;
                app.TwinQTOffLine.Value= app.QTOff ;
            end

            %% Fill Summary
            FillMiniSummary(app);

        end

        % Clicked callback: LeadsSelectListBox
        function LeadsListBoxClicked(app, event)
            % to selct the superimposed on the main axis plot.
            if ~isempty (app.ECGFileName)
                item = event.InteractionInformation.Item;
                if isscalar(item)
                    app.LeadId=item-2;
                    app.LeadTxt=app.LeadsSelectListBox.Items(item);
                    app.LeadsSelectListBox.Value=app.LeadTxt;
                end
                if app.LeadId==0
                    GUI_ECGPlot (2,app.ECGPlot,app.QRS,app.LeadId,1,app.LegendButton.Value);
                    app.ECGPlot.ButtonDownFcn = createCallbackFcn(app, @ECGPlotButtonDown, true);
                else
                    GUI_ECGPlot (2,app.ECGPlot,app.QRS,app.LeadId,0,app.LegendButton.Value);
                    app.ECGPlot.ButtonDownFcn = createCallbackFcn(app, @ECGPlotButtonDown, true);
                end
                app.QRSOnLine= xline(app.ECGPlot,app.QRSOn,'B',LineWidth=1.0);
                app.QRSOFFLine= xline(app.ECGPlot,app.QRSOff,'G',LineWidth=1.0);
                app.QTOffLine= xline(app.ECGPlot,app.QTOff,'M--',LineWidth=1.0);
                PlotTwin(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % To save the annotation data to the excel file.
            app.StatusLabel.Text='Saving... ';

            drawnow; % do pending callbacks
            tic
            AdditionalProvenanceString='-'; % Add more information to the record if you like!

            Provenance=strcat('/','CM_V1.0','<',app.ECGFileSelector.UserName,'>',app.ECGFileSelector.REFName1,'|',app.ECGFileSelector.REFName2,'|',AdditionalProvenanceString,'\');
            leadNames={'I','II','III','aVR','aVL','aVF','V1','V2','V3','V4','V5','V6'};
            MedianBeatControl={app.I,app.II,app.III,app.aVR,app.aVL,app.aVF,app.V1,app.V2,app.V3,app.V4,app.V5,app.V6};
            for i=1:12 % Collect annotation from the median beats.
                if MedianBeatControl{i}.MidNotch==1;NS='Notch';else;NS='_'  ; end
                if MedianBeatControl{i}.MidSlur==1;NS=strcat('Slur');end
                QRSSpecs.NS{i}=NS;
                QRSSpecs.NSoff(i)=MedianBeatControl{i}.NSoff;
                QRSSpecs.NSon(i)=MedianBeatControl{i}.NSon;
                QRSSpecs.RpeakI(i)=MedianBeatControl{i}.QRS_RPeak;
                QRSSpecs.QRSConfig{i}=MedianBeatControl{i}.QRS_Config;
                QRSSpecs.LeadName{i}=leadNames{i};
            end

            MissingData=1;
            if strcmp(app.QRSMorphologyDropDown.Value,'Not Selected')
                ErrorStr="Select QRS Morph";
            elseif sum( strcmp (string(QRSSpecs.QRSConfig),"N/A"))>0 || sum( strcmp (string(QRSSpecs.QRSConfig),""))>0
                ErrorStr='Missing QRS Pattern';
            else
                MissingData=0;
            end

            if app.SaveOnlyCompleteDataCheckBox.Value==0 || (app.SaveOnlyCompleteDataCheckBox.Value==1 && MissingData==0)
                SaveData2ExcelV2(app,app.ECGFileName,app.QRSMorphologyDropDown.Value,app.QRSOn,app.QRSd,app.QTd, QRSSpecs,app.CommentsTextArea.Value,Provenance); % Function to save to the excel file
                app.StatusLabel.Text='Saved Successfully';
                app.StatusLabel.FontColor=[0.0,1,0.0];
                if MissingData>0
                    app.StatusLabel.Text='Saved with missing data!';
                    app.StatusLabel.FontColor=[0.7,.9,0.5];
                end

            else
                app.StatusLabel.Text=ErrorStr;
                app.StatusLabel.FontColor=[0.90,0.70,0.15];
            end

            disp(toc)
        end

        % Value changed function: TwinLeadDropDown
        function TwinLeadDropDownValueChanged(app, event)
            % to select other lead other than the standard superimposed to be suwn on the secondry axis plot

            LeadId3= find(strcmp(app.TwinLeadDropDown.Items,app.TwinLeadDropDown.Value));LeadId3=LeadId3(1);

            if LeadId3>2
                GUI_ECGPlot (1,app.ECGPlot_2,app.QRS,LeadId3-2,0);
            elseif LeadId3==1
                GUI_ECGPlot (2,app.ECGPlot_2,app.QRS,LeadId3,0);
            else
                GUI_ECGPlot (2,app.ECGPlot_2,app.QRS,LeadId3,1);
            end
            app.TwinQRSOnLine=xline( app.ECGPlot_2,app.QRSOn,'B',LineWidth=1.0);
            app.TwinQRSOFFLine=xline(app.ECGPlot_2,app.QRSOff,'G',LineWidth=1.0);
            app.TwinQTOffLine=xline(app.ECGPlot_2,app.QTOff,'M--',LineWidth=1.0);
        end

        % Key press function: CardioMarkV10UIFigure
        function CardioMarkV10UIFigureKeyPress(app, event)
            % function for Key shortcuts specailly for mac user
            KEY = event.Key;
            if strcmp(KEY,'s') || strcmp(KEY,'1')
                app.KeyCommand=0;
                app.KeyCommandLabel.Text="Tip: Select QRS Onset";
                app.QRSOnsetButton.Value=1;
            elseif strcmp(KEY,'e') || strcmp(KEY,'2')
                app.KeyCommand=1;
                app.KeyCommandLabel.Text="Tip: Select QRS Offset";
                app.QRSOffsetButton.Value=1;
            elseif strcmp(KEY,'t') ||strcmp(KEY,'3')
                app.KeyCommand=2;
                app.KeyCommandLabel.Text="Tip: Select T Offset";
                app.TOffsetButton.Value=1;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Jump to the next tab
            app.TabGroup.SelectedTab=app.LimbLeadsTab;
            RefreshButtonPushed2(app, event)
        end

        % Button pushed function: HelpButton
        function HelpHelpButtonValueChanged(app, event)
            ShowHelpdialog()
        end

        % Selection changed function: BoundarySelectionButtonGroup
        function BoundarySelectionButtonGroupSelectionChanged(app, event)
            % Specially for MAX user, to use left click to select onsets and offsets.
            selectedButton = app.BoundarySelectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'QRS Onset')
                app.KeyCommand=0;
                app.KeyCommandLabel.Text="Tip: Select QRS Onset";
            elseif strcmp(selectedButton.Text,'QRS Offset')
                app.KeyCommand=1;
                app.KeyCommandLabel.Text="Tip: Select QRS Offset";
            elseif strcmp(selectedButton.Text,'T Offset')
                app.KeyCommand=2;
                app.KeyCommandLabel.Text="Tip: Select T Offset";
            end

        end

        % Value changed function: QRSButton
        function QRSButtonValueChanged(app, event)
            % Zoom into waveform between the selected QRS onset and QRS offset
            value = app.QRSButton.Value;
            ScaleFactor1=(1-app.LimitEditField.Value/100);
            ScaleFactor2=(1+app.LimitEditField.Value/100);
            if value==1
                if app.QTButton.Value==0
                    app.OrginalLimit=xlim(app.ECGPlot);
                else
                    app.QTButton.Value=0;
                end
                xlim(app.ECGPlot,[app.QRSOn*ScaleFactor1,app.QRSOff*ScaleFactor2]);
            else
                xlim(app.ECGPlot,app.OrginalLimit);
            end
        end

        % Value changed function: QTButton
        function QTButtonValueChanged(app, event)
            % Zoom into waveform between the selected Q onset and T offset
            value = app.QTButton.Value;
            ScaleFactor1=(1-app.LimitEditField.Value/100);
            ScaleFactor2=(1+app.LimitEditField.Value/100);
            if value==1
                if app.QRSButton.Value==0
                    app.OrginalLimit=xlim(app.ECGPlot);
                else
                    app.QRSButton.Value=0;
                end
                if app.QTOff>100
                    xlim(app.ECGPlot,[app.QRSOn*ScaleFactor1,app.QTOff*ScaleFactor2]);
                end
            else
                xlim(app.ECGPlot,app.OrginalLimit);
            end
        end

        % Value changed function: Gain2XSlider
        function Gain2XSliderValueChanged(app, event)
            % increase the gain of the superimposed waveform
            value = app.Gain2XSlider.Value;
            if app.OrginalLimitV(1)==0 && app.OrginalLimitV(2)==0
                app.OrginalLimitV=ylim(app.ECGPlot);
            end
            ylim(app.ECGPlot,app.OrginalLimitV./(2^value));
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed2(app, event)
            % Refresh all medaian beats
            BeatsMETADATA=[app.QRSOn,app.QRSOff,app.ECGFileSelector.Fs]; %
            LeadSpecsControl2={app.I,app.II,app.III,app.aVR,app.aVL,app.aVF,app.V1,app.V2,app.V3,app.V4,app.V5,app.V6};
            for i=1:12
                LeadSpecsControl2{i}.Update(BeatsMETADATA);
            end
        end

        % Button down function: LimbLeadsTab
        function LimbLeadsTabButtonDown(app, event)
            % Refresh plots
            RefreshButtonPushed2(app, event)
        end

        % Button pushed function: AutoR_PeaksButton
        function AutoR_PeaksButtonPushed(app, event)
            % Disabled command.  to Rpeak all median beats.
            RefreshButtonPushed2(app, event)
            MedianBeatControl={app.I,app.II,app.III,app.aVR,app.aVL,app.aVF,app.V1,app.V2,app.V3,app.V4,app.V5,app.V6};
            for i=1:12
                MedianBeatControl{i}.AutoRPeak;
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.TabGroup.SelectedTab=app.ChestLeadsTab;
            RefreshButtonPushed2(app, event)
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab=app.FullLeadsTab;
            RefreshButtonPushed2(app, event)
        end

        % Callback function: ECGFileSelector
        function ECGFileisSelected(app, event)
            %% stage 1 Initialisation and Reset to initial status.
            app.StatusLabel.Text='Loading... ';
            app.TabGroup.SelectedTab=app.SuperimposeTab; % go to default Tab
            app.StatusLabel.FontColor=[0.90,0.70,0.15]; % Change color of status text to orange ish
            app.WaitImage.Visible="on"; % HourGlass image for progress indication
            drawnow; % do pending callbacks

            app.LeadId=0;
            app.QRS= app.ECGFileSelector.Median; % QRS contains All median beat waveform and meta data
            app.ECGFileName=app.ECGFileSelector.FileName;
            app.FolderPath=app.ECGFileSelector.FolderPath;
            if length (app.ECGFileName)>5 && ~isnumeric(app.ECGFileSelector.Median)&&  length(app.ECGFileSelector.Median(1).X)>1 % just extra file name checking point
                % default Settings
                app.TabGroup.SelectedTab=app.SuperimposeTab; % go to default Tab
                app.LeadsSelectListBox.Value='Limb'; %%*** default
                app.QRSMorphologyDropDown.Value='Not Selected';
                app.Gain2XSlider.Value=0;
                GUI_ECGPlot (2,app.ECGPlot,app.QRS,app.LeadId,0,app.LegendButton.Value); % <<<<<<<<<<<<
                app.ECGPlot.ButtonDownFcn = createCallbackFcn(app, @ECGPlotButtonDown, true);

                app.QRSOn=1;app.QRSOff=1;app.QTOff=1; % Starting Position for annotation for future annotation
                app.QRSOnLine= xline( app.ECGPlot,app.QRSOn,'B',LineWidth=1.0,DisplayName='QRS Onset');
                app.QRSOFFLine= xline(app.ECGPlot,app.QRSOff,'G',LineWidth=1.0,DisplayName='QRS Offset');
                app.QTOffLine= xline(app.ECGPlot,app.QTOff,'M--',LineWidth=1.0,DisplayName='QT Offset');

                % % Plot All Lead in the second TAB and 3rd tab
                LeadSpecsControl2={app.I,app.II,app.III,app.aVR,app.aVL,app.aVF,app.V1,app.V2,app.V3,app.V4,app.V5,app.V6};

                BeatsMETADATA=[0,0,app.ECGFileSelector.Fs]; % Vector contains numbers needed for components

                for i=1:12 % change 12 if you are modifing the app for more or less leads.
                    % Get Observers annotation
                    [Ref1Info,Ref1NSLoc,Ref1UserName,Ref1QRSc,Ref1NS]=PerLeadRefInformation(app.ECGFileSelector.REFName1,app.ECGFileSelector.Ref1Record,i);
                    [Ref2Info,Ref2NSLoc,Ref2UserName,Ref2QRSc,Ref2NS]=PerLeadRefInformation(app.ECGFileSelector.REFName2,app.ECGFileSelector.Ref2Record,i);
                    % Reset component and plot the new median beat waveform and show observers work if any.
                    LeadSpecsControl2{i}.ResetControl;
                    LeadSpecsControl2{i}.PlotQRS(app.QRS(i).X,app.QRS(i).TS,app.QRS(i).Lead,BeatsMETADATA , [Ref1NSLoc,Ref2NSLoc]);
                    LeadSpecsControl2{i}.UpdateRef(Ref1Info,Ref2Info,Ref1UserName,Ref1QRSc,Ref1NS,Ref2UserName,Ref2QRSc,Ref2NS);
                end
                PlotTwin(app); % Plot secondory superimposed median beats.

                app.CommentsTextArea.Value='';
                app.StatusLabel.FontColor=[0,1,0];
                app.QRSOnsetButton.Value=1;
                app.KeyCommand=0; % for keyboard shortcut
                app.KeyCommandLabel.Text="Tip: Select QRS Onset";
                app.QRSButton.Value=0;
                app.QTButton.Value=0;
                app.Gain2XSlider.Value=0;
                app.OrginalLimitV=[0,0];

                if app.LoadFullLeadsCheckBox.Value==1
                    GUI_ECGLeadPlot (app.ECGLeadPlot,app.ECGFileSelector.Raw,app.PlotIdxes,1); % <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
                end

                YLoc=.95*app.ECGPlot.YLim;


                %% show Observers annotation in any and use

                if ~isempty(app.ECGFileSelector.Ref1Record)
                    app.Observer1CommentsLabel.Text=strcat( "Comments by ", app.ECGFileSelector.REFName1,  ": ",app.ECGFileSelector.Ref1Record.Comment) ;
                    app.Observer1QRSMorphologyLabel.Text=strcat( app.ECGFileSelector.REFName1,  ": ",app.ECGFileSelector.Ref1Record.QRSMorph) ;
                    Ref1QRS=[app.ECGFileSelector.Ref1Record.Onset,app.ECGFileSelector.Ref1Record.Onset+app.ECGFileSelector.Ref1Record.QRSd,...
                        app.ECGFileSelector.Ref1Record.Onset+app.ECGFileSelector.Ref1Record.QTd];
                    plot( app.ECGPlot,Ref1QRS,[YLoc(2),YLoc(2),YLoc(2)],'VG',DisplayName=app.ECGFileSelector.REFName1,LineWidth=3)
                    UseREFSets(app, app.UseRef1CheckBox.Value, app.ECGFileSelector.Ref1Record.Onset,app.ECGFileSelector.Ref1Record.QRSd,app.ECGFileSelector.Ref1Record.QTd );
                else
                    app.Observer1CommentsLabel.Text='';
                    app.Observer1QRSMorphologyLabel.Text='';
                end
                if ~isempty(app.ECGFileSelector.Ref2Record)
                    app.Observer2CommentsLabel.Text=strcat("Comments by ",app.ECGFileSelector.REFName2,  ": ",app.ECGFileSelector.Ref2Record.Comment) ;
                    app.Observer2QRSMorphologyLabel.Text=strcat( app.ECGFileSelector.REFName2,  ": ",app.ECGFileSelector.Ref2Record.QRSMorph) ;
                    Ref2QRS=[app.ECGFileSelector.Ref2Record.Onset,app.ECGFileSelector.Ref2Record.Onset+app.ECGFileSelector.Ref2Record.QRSd,...
                        app.ECGFileSelector.Ref2Record.Onset+app.ECGFileSelector.Ref2Record.QTd];
                    plot( app.ECGPlot,Ref2QRS,[YLoc(1),YLoc(1),YLoc(1)],'^R',DisplayName=app.ECGFileSelector.REFName2,LineWidth=3) %***
                    UseREFSets(app, app.UseRef2CheckBox.Value, app.ECGFileSelector.Ref2Record.Onset,app.ECGFileSelector.Ref2Record.QRSd,app.ECGFileSelector.Ref2Record.QTd );
                else
                    app.Observer2CommentsLabel.Text='';
                    app.Observer2QRSMorphologyLabel.Text='';
                end


                app.StatusLabel.Text='Ready!';


                FillMiniSummary(app);

            else

                app.StatusLabel.Text='Select Another file!';
                errordlg('Incompatible file data', 'CardioMark');
            end

            app.WaitImage.Visible="off";
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % to plot Full leads based on the selection of state buttons.
            BottomList={app.IButton,app.IIButton,app.IIIButton,app.aVRButton,app.aVLButton,app.aVFButton,app.V1Button,app.V2Button,app.V3Button,app.V4Button,app.V5Button,app.V6Button};
            app.PlotIdxes=[];
            for i=1:length(BottomList)
                if BottomList{i}.Value==1
                    app.PlotIdxes=[app.PlotIdxes,i];
                end
            end
            if isempty( app.PlotIdxes) % by default, Lead I will be selected.
                app.PlotIdxes= 1 ;
                app.IButton.Value=1;
            end
            GUI_ECGLeadPlot (app.ECGLeadPlot,app.ECGFileSelector.Raw,app.PlotIdxes,1);
        end

        % Button pushed function: GoRightButton
        function GoRightButtonPushed(app, event)
            % For fine shifting boundries for Onset and offsets to the right. GoStep can be changed to change the jump.
            GoStep=2;
            if  app.KeyCommand ==0
                app.QRSOn = app.QRSOn+GoStep;
                app.QRSOnLine.Value=app.QRSOn;
                app.TwinQRSOnLine.Value=app.QRSOn;

            elseif  (   app.KeyCommand ==1)
                app.QRSOff =app.QRSOff+GoStep;
                app.QRSOFFLine.Value= app.QRSOff;
                app.TwinQRSOFFLine.Value= app.QRSOff; % if app.ShowTwin==1
            elseif   app.KeyCommand ==2
                app.QTOff = app.QTOff+GoStep;
                app.QTOffLine.Value= app.QTOff ;
                app.TwinQTOffLine.Value= app.QTOff ;
            end

            FillMiniSummary(app);
        end

        % Button pushed function: GoLeftButton
        function GoLeftButtonPushed(app, event)
            %For fine shifting boundries for Onset and offsets to the left. GoStep can be changed to change the jump.
            GoStep=2;
            if  app.KeyCommand ==0
                app.QRSOn = app.QRSOn-GoStep;
                app.QRSOnLine.Value=app.QRSOn;
                app.TwinQRSOnLine.Value=app.QRSOn;

            elseif  (   app.KeyCommand ==1)
                app.QRSOff =app.QRSOff-GoStep;
                app.QRSOFFLine.Value= app.QRSOff;
                app.TwinQRSOFFLine.Value= app.QRSOff; % if app.ShowTwin==1
            elseif   app.KeyCommand ==2
                app.QTOff = app.QTOff-GoStep;
                app.QTOffLine.Value= app.QTOff ;
                app.TwinQTOffLine.Value= app.QTOff ;
            end
            FillMiniSummary(app);
        end

        % Button pushed function: CSVButton
        function CSVButtonPushed(app, event)

            try
                % Read the data from the ECG Excel File
                data = readtable(  app.ECGFileSelector.ExcelFileName);

                % Define the output CSV file name
                [~, name, ~] = fileparts(app.ECGFileSelector.ExcelFileName);
                csvFileName = [app.ECGFileSelector.FolderPath,'/',name, '.csv'];

                % Write the data to CSV
                writetable(data, csvFileName);

                %  Show export success message

                msgbox(['Conversion successful! CSV file saved as: ', csvFileName], 'CardioMark');
            catch ME
                % Show error message
                msgbox( 'Error, Try click <LOAD Files> command button first' , 'CardioMark', 'error');
            end

        end

        % Button pushed function: JSONButton
        function JSONButtonPushed(app, event)
            try
                % Read the data from the ECG Excel File
                data = readtable(app.ECGFileSelector.ExcelFileName);

                % Convert the table to a struct array for JSON compatibility
                dataStruct = table2struct(data);

                % Define the output JSON file name
                [~, name, ~] = fileparts(app.ECGFileSelector.ExcelFileName);
                JSONFileName = fullfile(app.ECGFileSelector.FolderPath, [name, '.json']);

                % Encode the struct as JSON and write to  the file
                jsonText = jsonencode(dataStruct);
                fid = fopen(JSONFileName, 'w');

                if fid == -1
                    error('Cannot access the file: %s', JSONFileName);
                end

                fwrite(fid, jsonText, 'char');
                fclose(fid);

                % Show export success message

                msgbox(['Conversion successful! JSON file saved as: ', JSONFileName], 'CardioMark');
            catch ME
                % Show error message
                msgbox( 'Error, Try click <LOAD Files> command button first', 'CardioMark', 'error');
            end

        end

        % Button pushed function: MATButton
        function MATButtonPushed(app, event)
            try
                % Read the data from the ECG Excel File
                data = readtable(app.ECGFileSelector.ExcelFileName);

                % Define the output MAT file name
                [~, name, ~] = fileparts(app.ECGFileSelector.ExcelFileName);
                MATFileName = fullfile(app.ECGFileSelector.FolderPath, [name, '.mat']);

                % Save the table to a MAT file
                save(MATFileName, 'data');

                % Show export success message
                msgbox(['Conversion successful! MAT file saved as: ', MATFileName], 'CardioMark');
            catch ME
                % Show error message
                msgbox('Error, Try click <LOAD Files> command button first', 'CardioMark', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CardioMarkV10UIFigure = uifigure('Visible', 'off');
            app.CardioMarkV10UIFigure.Name = 'CardioMark V1.0';
            app.CardioMarkV10UIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'CardioMarkIcon.jpg');
            app.CardioMarkV10UIFigure.KeyPressFcn = createCallbackFcn(app, @CardioMarkV10UIFigureKeyPress, true);
            app.SettingsPanel = uipanel(app.CardioMarkV10UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.UseonsetoffsetfromLabel = uilabel(app.SettingsPanel);
            app.UseonsetoffsetfromLabel.Text = 'Use onset & offset from';
            app.SaveOnlyCompleteDataCheckBox = uicheckbox(app.SettingsPanel);
            app.SaveOnlyCompleteDataCheckBox.Text = 'Save Only Complete Data';
            app.UseRef1CheckBox = uicheckbox(app.SettingsPanel);
            app.UseRef1CheckBox.Text = 'Observer 1';
            app.UseRef2CheckBox = uicheckbox(app.SettingsPanel);
            app.UseRef2CheckBox.Text = 'Observer 2';
            app.ECGFileSelector = ECGFileSelector(app.CardioMarkV10UIFigure);
            app.ECGFileSelector.REFName1 = 'DrRef1';
            app.ECGFileSelector.REFName2 = 'DrRef2';
            app.ECGFileSelector.XMLisSelectedFcn = createCallbackFcn(app, @ECGFileisSelected, true);
            app.SaveButton = uibutton(app.CardioMarkV10UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'resources', 'SaveIcon.png');
            app.SaveButton.Text = 'Save';
            app.StatusLabel = uilabel(app.CardioMarkV10UIFigure);
            app.StatusLabel.Text = '-';
            app.TabGroup = uitabgroup(app.CardioMarkV10UIFigure);
            app.SuperimposeTab = uitab(app.TabGroup);
            app.SuperimposeTab.Title = 'Superimpose';
            app.ECGPlot = uiaxes(app.SuperimposeTab);
            title(app.ECGPlot, 'Main ECG View')
            xlabel(app.ECGPlot, 'ms')
            ylabel(app.ECGPlot, 'mV')
            zlabel(app.ECGPlot, 'Z')
            app.ECGPlot.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ECGPlot.ButtonDownFcn = createCallbackFcn(app, @ECGPlotButtonDown, true);
            app.ECGPlot_2 = uiaxes(app.SuperimposeTab);
            title(app.ECGPlot_2, 'Secondary ECG')
            xlabel(app.ECGPlot_2, 'ms')
            ylabel(app.ECGPlot_2, 'mV')
            zlabel(app.ECGPlot_2, 'Z')
            app.LeadsListBoxLabel = uilabel(app.SuperimposeTab);
            app.LeadsListBoxLabel.Text = 'Main View Superimposed Leads Selection';
            app.BoundarySelectionButtonGroup = uibuttongroup(app.SuperimposeTab);
            app.BoundarySelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundarySelectionButtonGroupSelectionChanged, true);
            app.BoundarySelectionButtonGroup.Title = 'Boundary Selection';
            app.QRSOnsetButton = uiradiobutton(app.BoundarySelectionButtonGroup);
            app.QRSOnsetButton.Text = 'QRS Onset';
            app.QRSOnsetButton.Value = true;
            app.QRSOffsetButton = uiradiobutton(app.BoundarySelectionButtonGroup);
            app.QRSOffsetButton.Text = 'QRS Offset';
            app.TOffsetButton = uiradiobutton(app.BoundarySelectionButtonGroup);
            app.TOffsetButton.Text = 'T Offset';
            app.GoLeftButton = uibutton(app.BoundarySelectionButtonGroup, 'push');
            app.GoLeftButton.ButtonPushedFcn = createCallbackFcn(app, @GoLeftButtonPushed, true);
            app.GoLeftButton.Tooltip = {'Fine Adjusting to Left'};
            app.GoLeftButton.Text = '<<';
            app.GoRightButton = uibutton(app.BoundarySelectionButtonGroup, 'push');
            app.GoRightButton.ButtonPushedFcn = createCallbackFcn(app, @GoRightButtonPushed, true);
            app.GoRightButton.Tooltip = {'Fine Adjusting to Right'};
            app.GoRightButton.Text = '>>';
            app.QRSButton = uibutton(app.SuperimposeTab, 'state');
            app.QRSButton.ValueChangedFcn = createCallbackFcn(app, @QRSButtonValueChanged, true);
            app.QRSButton.Icon = fullfile(pathToMLAPP, 'resources', 'OIP.jpg');
            app.QRSButton.Text = 'QRS';
            app.LeadsSelectListBox = uilistbox(app.SuperimposeTab);
            app.LeadsSelectListBox.Items = {'Limb', 'Chest'};
            app.LeadsSelectListBox.ClickedFcn = createCallbackFcn(app, @LeadsListBoxClicked, true);
            app.LeadsSelectListBox.Value = 'Limb';
            app.QTButton = uibutton(app.SuperimposeTab, 'state');
            app.QTButton.ValueChangedFcn = createCallbackFcn(app, @QTButtonValueChanged, true);
            app.QTButton.Icon = fullfile(pathToMLAPP, 'resources', 'OIP.jpg');
            app.QTButton.Text = 'QT';
            app.LimitEditField = uieditfield(app.SuperimposeTab, 'numeric');
            app.LimitEditField.Limits = [0 100];
            app.LimitEditField.ValueDisplayFormat = '%11.4g%%';
            app.LimitEditField.Visible = 'off';
            app.LimitEditField.Value = 5;
            app.NextButton = uibutton(app.SuperimposeTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SecondaryAxisPlotLabel = uilabel(app.SuperimposeTab);
            app.SecondaryAxisPlotLabel.Text = 'Secondary Axis Plot';
            app.TwinLeadDropDown = uidropdown(app.SuperimposeTab);
            app.TwinLeadDropDown.Items = {'Limb', 'Chest', 'I', 'II', 'III', 'aVR', 'aVL', 'aVF', 'V1', 'V2', 'V3', 'V4', 'V5', 'V6'};
            app.TwinLeadDropDown.ValueChangedFcn = createCallbackFcn(app, @TwinLeadDropDownValueChanged, true);
            app.TwinLeadDropDown.Value = 'Chest';
            app.KeyCommandLabel = uilabel(app.SuperimposeTab);
            app.KeyCommandLabel.Text = '';
            app.LegendButton = uibutton(app.SuperimposeTab, 'state');
            app.LegendButton.Text = 'Legend';
            app.WaitImage = uiimage(app.SuperimposeTab);
            app.WaitImage.Enable = 'off';
            app.WaitImage.Visible = 'off';
            app.WaitImage.Tooltip = {'Just Wait :)'};
            app.WaitImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'dribbble-wait.gif');
            app.RefreshButton = uibutton(app.SuperimposeTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed2, true);
            app.RefreshButton.Visible = 'off';
            app.RefreshButton.Text = 'Refresh';
            app.AutoR_PeaksButton = uibutton(app.SuperimposeTab, 'push');
            app.AutoR_PeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AutoR_PeaksButtonPushed, true);
            app.AutoR_PeaksButton.Visible = 'off';
            app.AutoR_PeaksButton.Text = 'Auto R_Peaks';
            app.Gain2XSlider = uislider(app.SuperimposeTab);
            app.Gain2XSlider.Limits = [-2 4];
            app.Gain2XSlider.MajorTicks = [-2 -1 0 1 2 3 4];
            app.Gain2XSlider.MajorTickLabels = {'-2', '-1', '0', '1', '2', '3', '4', '5'};
            app.Gain2XSlider.ValueChangedFcn = createCallbackFcn(app, @Gain2XSliderValueChanged, true);
            app.Gain2XSlider.MinorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.Gain2XSliderLabel = uilabel(app.SuperimposeTab);
            app.Gain2XSliderLabel.Text = 'Gain 2^X';
            app.KeyCommandLabel_2 = uilabel(app.SuperimposeTab);
            app.KeyCommandLabel_2.Text = {'Press ''s'' or 1 for selecting QRS Onset.'; 'Press ''e'' or 2 for selecting QRS Offset.'; 'Press ''t'' or 3 for Selecting T Offset.'};
            app.ObserverDifferencesinmsifAnyPanel = uipanel(app.SuperimposeTab);
            app.ObserverDifferencesinmsifAnyPanel.Title = 'Observer Differences in ms ( if Any)';
            app.QRSonLabel_2 = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.QRSonLabel_2.Text = 'QRSon';
            app.QRSoffLabel_2 = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.QRSoffLabel_2.Text = 'QRSoff';
            app.QRSdLabel_3 = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.QRSdLabel_3.Text = 'QRSd';
            app.ToffLabel_2 = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.ToffLabel_2.Text = 'Toff';
            app.QTLabel_2 = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.QTLabel_2.Text = 'QT';
            app.Observer1On = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1On.Text = '-';
            app.Observer1Off = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1Off.Text = '-';
            app.Observer1QRSd = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1QRSd.Text = '-';
            app.Observer1Toff = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1Toff.Text = '-';
            app.Observer1QT = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1QT.Text = '-';
            app.Observer2On = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2On.Text = '-';
            app.Observer2Off = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2Off.Text = '-';
            app.Observer2QRSd = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2QRSd.Text = '-';
            app.Observer2Toff = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2Toff.Text = '-';
            app.Observer2QT = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2QT.Text = '-';
            app.Observer1Label = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer1Label.Tooltip = {'Comments by Observer 1'};
            app.Observer1Label.Text = 'Observer 1:';
            app.Observer2Label = uilabel(app.ObserverDifferencesinmsifAnyPanel);
            app.Observer2Label.Tooltip = {'Comments by Observer 2'};
            app.Observer2Label.Text = 'Observer 2:';
            app.AutoRPeakCheckBox = uicheckbox(app.SuperimposeTab);
            app.AutoRPeakCheckBox.Visible = 'off';
            app.AutoRPeakCheckBox.Text = 'Auto R Peak';
            app.LoadFullLeadsCheckBox = uicheckbox(app.SuperimposeTab);
            app.LoadFullLeadsCheckBox.Visible = 'off';
            app.LoadFullLeadsCheckBox.Text = 'Load Full Leads';
            app.LimbLeadsTab = uitab(app.TabGroup);
            app.LimbLeadsTab.Title = 'Limb Leads';
            app.LimbLeadsTab.ButtonDownFcn = createCallbackFcn(app, @LimbLeadsTabButtonDown, true);
            app.I = MedianBeat_Config(app.LimbLeadsTab);
            app.II = MedianBeat_Config(app.LimbLeadsTab);
            app.III = MedianBeat_Config(app.LimbLeadsTab);
            app.aVR = MedianBeat_Config(app.LimbLeadsTab);
            app.aVL = MedianBeat_Config(app.LimbLeadsTab);
            app.aVF = MedianBeat_Config(app.LimbLeadsTab);
            app.NextButton_2 = uibutton(app.LimbLeadsTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.ChestLeadsTab = uitab(app.TabGroup);
            app.ChestLeadsTab.Title = 'Chest Leads';
            app.V1 = MedianBeat_Config(app.ChestLeadsTab);
            app.V2 = MedianBeat_Config(app.ChestLeadsTab);
            app.V3 = MedianBeat_Config(app.ChestLeadsTab);
            app.V4 = MedianBeat_Config(app.ChestLeadsTab);
            app.V5 = MedianBeat_Config(app.ChestLeadsTab);
            app.V6 = MedianBeat_Config(app.ChestLeadsTab);
            app.NextButton_3 = uibutton(app.ChestLeadsTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.FullLeadsTab = uitab(app.TabGroup);
            app.FullLeadsTab.Title = 'Full Leads';
            app.ECGLeadPlot = uiaxes(app.FullLeadsTab);
            title(app.ECGLeadPlot, 'ECG')
            xlabel(app.ECGLeadPlot, 'ms')
            ylabel(app.ECGLeadPlot, 'mV')
            zlabel(app.ECGLeadPlot, 'Z')
            app.LeadsforPlotPanel = uipanel(app.FullLeadsTab);
            app.LeadsforPlotPanel.Title = 'Leads for Plot';
            app.IButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.IButton.Text = 'I';
            app.IButton.Value = true;
            app.V1Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V1Button.Text = 'V1';
            app.IIButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.IIButton.Text = 'II';
            app.IIIButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.IIIButton.Text = 'III';
            app.aVRButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.aVRButton.Text = 'aVR';
            app.aVLButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.aVLButton.Text = 'aVL';
            app.aVFButton = uibutton(app.LeadsforPlotPanel, 'state');
            app.aVFButton.Text = 'aVF';
            app.V2Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V2Button.Text = 'V2';
            app.V3Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V3Button.Text = 'V3';
            app.V4Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V4Button.Text = 'V4';
            app.V5Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V5Button.Text = 'V5';
            app.V6Button = uibutton(app.LeadsforPlotPanel, 'state');
            app.V6Button.Text = 'V6';
            app.PlotButton = uibutton(app.LeadsforPlotPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MeasurmentsmsPanel = uipanel(app.CardioMarkV10UIFigure);
            app.MeasurmentsmsPanel.Title = 'Measurments ms';
            app.QRSonLabel = uilabel(app.MeasurmentsmsPanel);
            app.QRSonLabel.Text = 'QRSon';
            app.QRSoffLabel = uilabel(app.MeasurmentsmsPanel);
            app.QRSoffLabel.Text = 'QRSoff';
            app.QRSdLabel_2 = uilabel(app.MeasurmentsmsPanel);
            app.QRSdLabel_2.Text = 'QRSd';
            app.ToffLabel = uilabel(app.MeasurmentsmsPanel);
            app.ToffLabel.Text = 'Toff';
            app.QTLabel = uilabel(app.MeasurmentsmsPanel);
            app.QTLabel.Text = 'QT';
            app.QRSOnLbl = uilabel(app.MeasurmentsmsPanel);
            app.QRSOnLbl.Text = '-';
            app.QRSoffLbl = uilabel(app.MeasurmentsmsPanel);
            app.QRSoffLbl.Text = '-';
            app.QRSdLbl = uilabel(app.MeasurmentsmsPanel);
            app.QRSdLbl.Text = '-';
            app.ToffLbl = uilabel(app.MeasurmentsmsPanel);
            app.ToffLbl.Text = '-';
            app.QTLbl = uilabel(app.MeasurmentsmsPanel);
            app.QTLbl.Text = '-';
            app.CommentsPanel = uipanel(app.CardioMarkV10UIFigure);
            app.CommentsPanel.Title = 'Comments';
            app.CommentsTextArea = uitextarea(app.CommentsPanel);
            app.CommentsTextArea.Tooltip = {'Comments by the user'};
            app.CommentsTextArea.Placeholder = 'Any Thoughts';
            app.Observer1CommentsLabel = uilabel(app.CommentsPanel);
            app.Observer1CommentsLabel.Tooltip = {'Comments by Observer 1'};
            app.Observer1CommentsLabel.Text = 'Observer 1 Comments';
            app.Observer2CommentsLabel = uilabel(app.CommentsPanel);
            app.Observer2CommentsLabel.Tooltip = {'Comments by Observer 2'};
            app.Observer2CommentsLabel.Text = 'Observer 2 Comments';
            app.QRSMorphologyPanel = uipanel(app.CardioMarkV10UIFigure);
            app.QRSMorphologyPanel.Title = 'QRS Morphology ';
            app.QRSMorphologyDropDown = uidropdown(app.QRSMorphologyPanel);
            app.QRSMorphologyDropDown.Items = {'Not Selected', 'Normal', 'LBBB-type', 'RBBB-type', 'IVCD', 'Paced', 'Others', '-'};
            app.QRSMorphologyDropDown.Value = 'Not Selected';
            app.Observer1QRSMorphologyLabel = uilabel(app.QRSMorphologyPanel);
            app.Observer1QRSMorphologyLabel.Text = 'Observer 1 QRS Morphology ';
            app.Observer2QRSMorphologyLabel = uilabel(app.QRSMorphologyPanel);
            app.Observer2QRSMorphologyLabel.Text = 'Observer 2 QRS Morphology ';
            app.HelpButton = uibutton(app.CardioMarkV10UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpHelpButtonValueChanged, true);
            app.HelpButton.Text = '?';
            app.AnnotationExportPanel = uipanel(app.CardioMarkV10UIFigure);
            app.AnnotationExportPanel.Tooltip = {'Annotation Export to CSV, MAT, and JSON'};
            app.AnnotationExportPanel.Title = 'Annotation Export';
            app.CSVButton = uibutton(app.AnnotationExportPanel, 'push');
            app.CSVButton.ButtonPushedFcn = createCallbackFcn(app, @CSVButtonPushed, true);
            app.CSVButton.Text = 'CSV';
            app.JSONButton = uibutton(app.AnnotationExportPanel, 'push');
            app.JSONButton.ButtonPushedFcn = createCallbackFcn(app, @JSONButtonPushed, true);
            app.JSONButton.Text = 'JSON';
            app.MATButton = uibutton(app.AnnotationExportPanel, 'push');
            app.MATButton.ButtonPushedFcn = createCallbackFcn(app, @MATButtonPushed, true);
            app.MATButton.Text = 'MAT';
            % Show the figure after all components are created
            app.CardioMarkV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CardioMark

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CardioMarkV10UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.CardioMarkV10UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardioMarkV10UIFigure)
        end
    end
end