classdef ECGFileSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        FilesListBox              matlab.ui.control.ListBox
        Panel_2                   matlab.ui.container.Panel
        TestModeCheckBox          matlab.ui.control.CheckBox
        FontPlusButton            matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        Panel_4                   matlab.ui.container.Panel
        ExportECGtoMATButton      matlab.ui.control.Button
        CopyButton                matlab.ui.control.Button
        FsLabel                   matlab.ui.control.Label
        FsLabelLabel              matlab.ui.control.Label
        ECGFileNameTxt            matlab.ui.control.EditField
        FileNameLabel             matlab.ui.control.Label
        FileCount                 matlab.ui.control.Label
        FilesListBoxLabel         matlab.ui.control.Label
        Msgs                      matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        Observer1EditField        matlab.ui.control.EditField
        Observer2EditField        matlab.ui.control.EditField
        Observer2EditFieldLabel   matlab.ui.control.Label
        Observer1EditFieldLabel   matlab.ui.control.Label
        UsernameEditField         matlab.ui.control.EditField
        UsernameEditFieldLabel    matlab.ui.control.Label
        FileTypeButtonGroup       matlab.ui.container.ButtonGroup
        SelectMedianFolderButton  matlab.ui.control.Button
        SelectRawFolderButton     matlab.ui.control.Button
        MainEditField             matlab.ui.control.EditField
        MedianLabel               matlab.ui.control.Label
        RawEditField              matlab.ui.control.EditField
        RawEditFieldLabel         matlab.ui.control.Label
        FileTypeLabel             matlab.ui.control.Label
        MATButton                 matlab.ui.control.ToggleButton
        HEAButton                 matlab.ui.control.ToggleButton
        XMLButton                 matlab.ui.control.ToggleButton
        HideDoneButton            matlab.ui.control.Button
        LoadFilesButton           matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        XMLisSelected % XMLisSelected
    end

    properties (Access = public)
        ECGFileExtension
        ExcelFileName
        FileName
        FileNo (1,1) double = 1;
        FilesinDir
        FolderPath
        FolderRawPath = ''; % Folder of Raw Data, Not applicable for XML and MAT
        Fs
        Gender
        Leads
        Median
        NoofFiles
        OnOffMatrix
        QMD % MUSE DATA
        REFName1 = 'DrObserver1';
        REFName2 = 'DrObserver2';
        Raw
        Ref1Record % Ref   Record from the table
        Ref1Table % Ref 1 Table for all entires
        Ref2Record
        Ref2Table % Ref 2 Table
        RefExcelFileName1 = '';
        RefExcelFileName2 = '';
        RefExist (1,2) = [0,0]; % to flag if there is an excel file for Ref 1 and Ref 2
        SettingArray
        Ts
        UserName = 'DrWho';
    end
    
    %% Comments
    %[11-06-2025]
    % CardioMark: A user-friendly tool for manually annotating ECG recordings.
    % Enables precise identification of QRS onset, QRS offset, and T-wave offset
    % for calculating QRS duration (QRSd) and QT interval. Features a GUI with
    % intuitive tools for waveform labeling, slur/notch detection, and heartbeat
    % classification. Supports multiple ECG file formats and exports annotations
    % for seamless integration with AI systems or external review.
    %% Citation:
    % S. Abdel-Rahman et al., "Faster R-CNN approach for estimating global QRS duration
    % in electrocardiograms with a limited quantity of annotated data," Comput. Biol. Med.,
    % vol. 192, p. 110200, 2025. https://doi.org/10.1016/j.compbiomed.2025.110200


    methods (Access = public)
        function results =IncreaseFont(comp)
            results=0;
            comp.FilesListBox.FontSize=comp.FilesListBox.FontSize+1;
        end
        % This fucntion is to create struct for the variable ALL "QRS"  and to plot the beat with GT and with QRS Peak
        function results = HideDone(comp,HideList)
            % try
            if ~isempty(HideList)
                [~,idx] = ismember(HideList,comp.FilesListBox.Items );
                if ~isempty(idx)
                    idx(idx==0)=[];
                    comp.FilesListBox.Items(idx) = [];
                    NoofRec=length(unique(idx));
                end
            end
            comp.NoofFiles=width(comp.FilesListBox.Items);
            comp.FileCount.Text=num2str( comp.NoofFiles);
            results=strcat(num2str(NoofRec), ' Files have been hidden! ');
            % catch
            % results='No HideDone';
            % end
        end
    end
    methods (Access = private)
        function results = UpdateNames(comp)
            comp.UserName=comp.UsernameEditField.Value;
            comp.REFName1=comp.Observer1EditField.Value;
            comp.REFName2=comp.Observer2EditField.Value;
            comp.ExcelFileName=strcat( comp.FolderPath,'/','_',comp.UserName,'-ECG_Study.xlsx');
            comp.RefExcelFileName1=strcat( comp.FolderPath,'/','_',comp.REFName1,'-ECG_Study.xlsx');
            comp.RefExcelFileName2=strcat( comp.FolderPath,'/','_',comp.REFName2,'-ECG_Study.xlsx');
            if exist(comp.RefExcelFileName1, 'file') == 2
                % % % disp('File exists.');
                comp.RefExist(1)=1;
                comp.Ref1Table=readtable( comp.RefExcelFileName1,VariableNamingRule="preserve");% <<<<<<<<<<<<<
            else
                comp.Observer1EditField.Value='';
                comp.RefExist(1)=0;
            end
            if exist(comp.RefExcelFileName2, 'file') == 2
                % % % disp('File exists.');
                comp.RefExist(2)=1;
                comp.Ref2Table=readtable(comp.RefExcelFileName2,VariableNamingRule="preserve"); % <<<<<<<<<<<<<
            else
                comp.Observer2EditField.Value='';
                comp.RefExist(2)=0;
            end
            results='UpdateNames';
        end
        function results = SaveParameter(comp)
            % Get the current working directory
            currentFolder = pwd;
            % Save a variable in the current working directory
            LocalArray.MBFolder =comp.MainEditField.Value;
            LocalArray.RawFolder =comp.RawEditField.Value;
            LocalArray.UserName =comp.UsernameEditField.Value;
            LocalArray.Ref1UserName =comp.Observer1EditField.Value;
            LocalArray.Ref2UserName =comp.Observer2EditField.Value;
            LocalArray.ECGExtension=comp.ECGFileExtension;

            LocalArray.FileType=[ comp.XMLButton.Value,comp.MATButton.Value,comp.HEAButton.Value];
            % save (fullfile(currentFolder,"CardioMarkParameter.mat"),"LocalArray", '-append')
            save (fullfile(currentFolder,"CardioMarkParameter.mat"),"LocalArray")
            results="save is OK";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            currentFolder = pwd;

            % to upload saved file manager settings for next session
            MATFile=fullfile(currentFolder,"CardioMarkParameter.mat");
            if exist(MATFile, 'file') == 2
                load (MATFile,"LocalArray");
                comp.MainEditField.Value=  LocalArray.MBFolder ;
                comp.RawEditField.Value=LocalArray.RawFolder;
                comp.UsernameEditField.Value=LocalArray.UserName ;
                comp.Observer1EditField.Value=LocalArray.Ref1UserName;
                comp.Observer2EditField.Value=LocalArray.Ref2UserName ;
                comp.XMLButton.Value=LocalArray.FileType(1);
                comp.MATButton.Value=LocalArray.FileType(2);
                comp.HEAButton.Value=LocalArray.FileType(3);
                comp.ECGFileExtension= LocalArray.ECGExtension;
            else
                comp.ECGFileExtension= 'HEA';
                comp.XMLButton.Value=0;
                comp.HEAButton.Value=1;
                comp.MATButton.Value=0;
            end

        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(comp, event)
            comp.Msgs.Text="-";
            appFile = mfilename('fullpath');appDir = fileparts(appFile); cd(appDir);%addpath GUI_Functions/
            comp.FolderPath=comp.MainEditField.Value  ;
            comp.FolderRawPath=comp.RawEditField.Value  ;
            Slash= '';
            if ~isempty(comp.FolderPath)
                Slash= '/';
            end
            comp.FilesinDir=dir(strcat(comp.FolderPath,Slash,'*.', comp.ECGFileExtension));
            MusefileNames = { comp.FilesinDir(~[ comp.FilesinDir.isdir]).name};  % Extract only the file names
            comp.FilesListBox.Items = MusefileNames;
            comp.NoofFiles =num2str(length(comp.FilesListBox.Items));
            comp.FileCount.Text=comp.NoofFiles;
            comp.FilesListBox.Enable="on";%app.LeadsListBox.Enable="on";
            comp.HideDoneButton.Enable=1;
            UpdateNames(comp);
            SaveParameter(comp);
        end

        % Clicked callback: FilesListBox
        function FilesListBoxClicked(comp, event)

            try
                item = event.InteractionInformation.Item;
                if isscalar(item)
                    comp.FileNo =item;
                end
                comp.FileCount.Text= strcat( num2str(comp.FileNo),'/', num2str( comp.NoofFiles));
                comp.FileName=comp.FilesListBox.Items(comp.FileNo); % to Update the Value Quickly
                comp.FileName=comp.FileName{1,1};
                comp.FilesListBox.Value=comp.FileName;
                %% Load and read ECG Files!
                [comp.Raw,comp.Median]=LoadECGFile(comp.FolderPath,comp.FolderRawPath,comp.FileName,comp.ECGFileExtension);
                if ~isnumeric(comp.Raw) % to check if the file is bad, will ignore it!

                    comp.ECGFileNameTxt.Value=comp.FileName;%****
                    comp.FsLabel.Text=num2str(comp.Raw(1).Fs); %****
                    comp.Fs=comp.Median(1).Fs;
                    %% Testing reading
                    if comp.TestModeCheckBox.Value==1
                        subplot(2,1,1)
                        for i=1:12
                            plot (comp.Median(i).TS,comp.Median(i).X); hold on;
                        end
                        subplot(2,1,2)
                        for i=1:12
                            plot (comp.Raw(i).X); hold on;
                        end
                        hold off
                    end

                    %% Retrieve the record from the reference tables.
                    if comp.RefExist(1)>0 % There is a REF 1 excel File!
                        comp.Ref1Record = GetLastTableRecord( comp.Ref1Table, comp.FileName);
                    end
                    if comp.RefExist(2)>0 % There is a REF 1 excel File!
                        comp.Ref2Record = GetLastTableRecord( comp.Ref2Table, comp.FileName);
                    end
                    if comp.TestModeCheckBox.Value==1
                        disp([comp.Ref1Record])
                        disp([comp.Ref2Record])
                    end

                end
                drawnow
                notify(comp,"XMLisSelected") %***

            catch
                errordlg('Incompatible file data', 'CardioMark');
            end

        end

        % Button pushed function: HideDoneButton
        function HideDoneButtonPushed(comp, event)
            try
                HideList = readtable(comp.ExcelFileName, 'Range', 'A:A'); % , 'Sheet', SheetName

                FunStr=HideDone(comp,HideList {:,1});
                comp.Msgs.Text=FunStr;
            catch
            end
        end

        % Button pushed function: FontPlusButton
        function FontPlusButtonPushed(comp, event)
            comp.IncreaseFont
        end

        % Button pushed function: SelectMedianFolderButton
        function SelectMedianFolderButtonPushed(comp, event)
            selectedFolder = uigetdir(pwd, 'Select Database Folder'); % Open the folder selection dialog
            if selectedFolder == 0 % Check if the user canceled or selected a valid folder
                disp('No folder selected. Please try again.');
            else
                comp.MainEditField.Value= selectedFolder;
            end
            SaveParameter(comp);
        end

        % Selection changed function: FileTypeButtonGroup
        function FileTypeButtonGroupSelectionChanged(comp, event)
            selectedButton = comp.FileTypeButtonGroup.SelectedObject;
            comp.ECGFileExtension=selectedButton.Text  ; %    disp(selectedButton.Text)
            SaveParameter(comp);
            % disp(selectedButton.Value   )
        end

        % Value changed function: Observer1EditField
        function Observer1EditFieldValueChanged(comp, event)
            value = comp.Observer1EditField.Value;
            comp.REFName1=value;
        end

        % Button pushed function: SelectRawFolderButton
        function SelectRawFolderButtonPushed(comp, event)
            selectedFolder = uigetdir(pwd, 'Select Database Folder for Raw ECG'); % Open the folder selection dialog
            if selectedFolder == 0 % Check if the user canceled or selected a valid folder
                disp('No folder selected. Please try again.');
            else
                comp.RawEditField.Value= selectedFolder;
            end
            SaveParameter(comp);
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(comp, event)
            clipboard('copy', comp.ECGFileNameTxt.Value);
        end

        % Button pushed function: ExportECGtoMATButton
        function ExportECGtoMATButtonPushed(comp, event)
            Raw=comp.Raw;
            MedianBeat=comp.Median;
            save( strcat( comp.FolderPath,'\',comp.FileName,'.MAT'),'Raw','MedianBeat')

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 -300 228 689];
            comp.GridLayout = uigridlayout(comp);
            comp.Panel = uipanel(comp.GridLayout);
            comp.LoadFilesButton = uibutton(comp.Panel, 'push');
            comp.LoadFilesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadFilesButtonPushed, true);
            comp.LoadFilesButton.Tooltip = {'Load ECG files'};
            comp.LoadFilesButton.Text = 'Load Files';
            comp.HideDoneButton = uibutton(comp.Panel, 'push');
            comp.HideDoneButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @HideDoneButtonPushed, true);
            comp.HideDoneButton.Enable = 'off';
            comp.HideDoneButton.Tooltip = {'Hide annotated ECG files'};
            comp.HideDoneButton.Text = 'Hide Done';
            comp.FileTypeButtonGroup = uibuttongroup(comp.Panel);
            comp.FileTypeButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @FileTypeButtonGroupSelectionChanged, true);
            comp.XMLButton = uitogglebutton(comp.FileTypeButtonGroup);
            comp.XMLButton.Tooltip = {'XML MUSE Files'};
            comp.XMLButton.Text = 'XML';
            comp.HEAButton = uitogglebutton(comp.FileTypeButtonGroup);
            comp.HEAButton.Tooltip = {'File similar to those used in PhysioNet datasets'};
            comp.HEAButton.Text = 'HEA';
            comp.HEAButton.Value = true;
            comp.MATButton = uitogglebutton(comp.FileTypeButtonGroup);
            comp.MATButton.Tooltip = {'MATLAB Data Files'};
            comp.MATButton.Text = 'MAT';
            comp.FileTypeLabel = uilabel(comp.FileTypeButtonGroup);
            comp.FileTypeLabel.Tooltip = {'Select file type'};
            comp.FileTypeLabel.Text = 'File Type:';
            comp.RawEditFieldLabel = uilabel(comp.FileTypeButtonGroup);
            comp.RawEditFieldLabel.Tooltip = {'Select folder for Raw ECG file'};
            comp.RawEditFieldLabel.Text = 'Raw:';
            comp.RawEditField = uieditfield(comp.FileTypeButtonGroup, 'text');
            comp.RawEditField.Tooltip = {'[optional ]Type or select RAW data folder by clicking on the button '};
            comp.RawEditField.Value = 'ECG Samples\Raw';
            comp.MedianLabel = uilabel(comp.FileTypeButtonGroup);
            comp.MedianLabel.Tooltip = {'Path of the folder that contains XML Files!'};
            comp.MedianLabel.Text = 'Main:';
            comp.MainEditField = uieditfield(comp.FileTypeButtonGroup, 'text');
            comp.MainEditField.CharacterLimits = [0 350];
            comp.MainEditField.Tooltip = {'Type or select median beat folder by clicking on the button '};
            comp.MainEditField.Value = 'ECG Samples\medians';
            comp.SelectRawFolderButton = uibutton(comp.FileTypeButtonGroup, 'push');
            comp.SelectRawFolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectRawFolderButtonPushed, true);
            comp.SelectRawFolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'SearchFolder.png');
            comp.SelectRawFolderButton.Tooltip = {'Select folder for Raw ECG file'};
            comp.SelectRawFolderButton.Text = '';
            comp.SelectMedianFolderButton = uibutton(comp.FileTypeButtonGroup, 'push');
            comp.SelectMedianFolderButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectMedianFolderButtonPushed, true);
            comp.SelectMedianFolderButton.Icon = fullfile(pathToMLAPP, 'resources', 'SearchFolder.png');
            comp.SelectMedianFolderButton.Tooltip = {'Select folder for the ECG file of XML and MAT formats, or the median ECG file of a HEA format'};
            comp.SelectMedianFolderButton.Text = '';
            comp.Panel_3 = uipanel(comp.Panel);
            comp.UsernameEditFieldLabel = uilabel(comp.Panel_3);
            comp.UsernameEditFieldLabel.Tooltip = {'Username'};
            comp.UsernameEditFieldLabel.Text = 'Username:';
            comp.UsernameEditField = uieditfield(comp.Panel_3, 'text');
            comp.UsernameEditField.CharacterLimits = [0 20];
            comp.UsernameEditField.Tooltip = {'Type Username here'};
            comp.UsernameEditField.Value = 'DrWho';
            comp.Observer1EditFieldLabel = uilabel(comp.Panel_3);
            comp.Observer1EditFieldLabel.Tooltip = {'Observer 1 Name'};
            comp.Observer1EditFieldLabel.Text = 'Observer 1 Name:';
            comp.Observer2EditFieldLabel = uilabel(comp.Panel_3);
            comp.Observer2EditFieldLabel.Tooltip = {'Observer 2 Name'};
            comp.Observer2EditFieldLabel.Text = 'Observer 2 Name:';
            comp.Observer2EditField = uieditfield(comp.Panel_3, 'text');
            comp.Observer2EditField.CharacterLimits = [0 20];
            comp.Observer2EditField.Tooltip = {'Observer 2 Name'};
            comp.Observer2EditField.Value = 'Observer2';
            comp.Observer1EditField = uieditfield(comp.Panel_3, 'text');
            comp.Observer1EditField.CharacterLimits = [0 20];
            comp.Observer1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Observer1EditFieldValueChanged, true);
            comp.Observer1EditField.Tooltip = {'Observer 1 Name'};
            comp.Observer1EditField.Value = 'Observer1';
            comp.Msgs = uilabel(comp.Panel);
            comp.Msgs.Text = '_';
            comp.FilesListBoxLabel = uilabel(comp.Panel);
            comp.FilesListBoxLabel.Text = 'File(s):';
            comp.FileCount = uilabel(comp.Panel);
            comp.FileCount.Tooltip = {'Selected file #: [current] / [total]'};
            comp.FileCount.Text = 'XXXX/XXXX';
            comp.Panel_4 = uipanel(comp.Panel);
            comp.FileNameLabel = uilabel(comp.Panel_4);
            comp.FileNameLabel.Text = 'File Name:';
            comp.ECGFileNameTxt = uieditfield(comp.Panel_4, 'text');
            comp.ECGFileNameTxt.Editable = 'off';
            comp.ECGFileNameTxt.Tooltip = {'File Name, you can use Ctrl+C to copy file name after selection.'};
            comp.FsLabelLabel = uilabel(comp.Panel_4);
            comp.FsLabelLabel.Text = 'Fs:';
            comp.FsLabel = uilabel(comp.Panel_4);
            comp.FsLabel.Text = '250-1000Hz';
            comp.CopyButton = uibutton(comp.Panel_4, 'push');
            comp.CopyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @CopyButtonPushed, true);
            comp.CopyButton.Icon = fullfile(pathToMLAPP, 'resources', 'copy.png');
            comp.CopyButton.Tooltip = {'Copy File Name to clipboard'};
            comp.CopyButton.Text = '';
            comp.ExportECGtoMATButton = uibutton(comp.Panel_4, 'push');
            comp.ExportECGtoMATButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportECGtoMATButtonPushed, true);
            comp.ExportECGtoMATButton.Tooltip = {'Export XML or HEA file to MAT format'};
            comp.ExportECGtoMATButton.Text = 'Export ECG to MAT';
            comp.Panel_2 = uipanel(comp.GridLayout);
            comp.FontPlusButton = uibutton(comp.Panel_2, 'push');
            comp.FontPlusButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FontPlusButtonPushed, true);
            comp.FontPlusButton.Tooltip = {'Increase Font'};
            comp.FontPlusButton.Text = '+';
            comp.TestModeCheckBox = uicheckbox(comp.Panel_2);
            comp.TestModeCheckBox.Visible = 'off';
            comp.TestModeCheckBox.Tooltip = {'Test Mode to plot file independtly to CardioMark'};
            comp.TestModeCheckBox.Text = 'Test Mode';
            comp.FilesListBox = uilistbox(comp.GridLayout);
            comp.FilesListBox.Items = {''};
            comp.FilesListBox.Enable = 'off';
            comp.FilesListBox.Tooltip = {'Select a file to start annotation.'};
            comp.FilesListBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @FilesListBoxClicked, true);
            comp.FilesListBox.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end