classdef MedianBeat_Config < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        ToolBoxPanel            matlab.ui.container.Panel
        Help                    matlab.ui.control.StateButton
        HelpGridButton          matlab.ui.control.Button
        RPeakSel                matlab.ui.control.StateButton
        EndSel                  matlab.ui.control.StateButton
        StartSel                matlab.ui.control.StateButton
        ZoomHome                matlab.ui.control.Button
        ZoomSelectionButtom     matlab.ui.control.StateButton
        Lamp                    matlab.ui.control.Lamp
        ZoomQRS                 matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
        GridLayout2             matlab.ui.container.GridLayout
        Panel_2                 matlab.ui.container.Panel
        FilterButton_3          matlab.ui.control.StateButton
        SmothFactor             matlab.ui.control.NumericEditField
        Panel                   matlab.ui.container.Panel
        Button                  matlab.ui.control.Button
        Ref2QRSc                matlab.ui.control.Label
        UseREF2Button           matlab.ui.control.Button
        Ref2NSLabel             matlab.ui.control.Label
        Observer2Label          matlab.ui.control.Label
        UseREF1Button           matlab.ui.control.Button
        Ref1NSLabel             matlab.ui.control.Label
        Ref1QRSc                matlab.ui.control.Label
        Observer1Label          matlab.ui.control.Label
        ObserversFeedbackLabel  matlab.ui.control.Label
        RPeakLabel              matlab.ui.control.Label
        RPeakButton             matlab.ui.control.Button
        SlurButton              matlab.ui.control.StateButton
        NotchButton             matlab.ui.control.StateButton
        QRSPatternButtonGroup   matlab.ui.container.ButtonGroup
        FragmentedButton        matlab.ui.control.RadioButton
        SButton_2               matlab.ui.control.StateButton
        sButton_2               matlab.ui.control.StateButton
        rButton_2               matlab.ui.control.StateButton
        sButton                 matlab.ui.control.StateButton
        rButton                 matlab.ui.control.StateButton
        qButton                 matlab.ui.control.StateButton
        RButton_2               matlab.ui.control.StateButton
        SButton                 matlab.ui.control.StateButton
        RButton                 matlab.ui.control.StateButton
        QButton                 matlab.ui.control.StateButton
        NAButton                matlab.ui.control.RadioButton
        QRSConfigTextBox        matlab.ui.control.EditField
        TypeQRSc                matlab.ui.control.RadioButton
    end

    properties (Access = public)
        BEATMETADATA = [0,0,0];
        MidNotch = 0; % Mid Notch
        MidSlur = 0; % MidSlur
        NSRefLoc = [0,0]; % NS Location for REF 1 and Ref 2 
        NSoff = 0;
        NSoffLine
        NSon = 0;
        NSonLine
        OrginalXLimit % OrginalLimit for zooming
        OrginalXYLimit
        PlotHandler
        QRSAxes
        QRS_Config = 'N/A';
        QRS_RPeak = 0;
        QRSoff = 1;
        QRSon = 1; % QRS onset Value
        RPeak
        RPeakLine
        TS
        X
    end
    
    %% Comments
    %[11-06-2025]
    % CardioMark: A user-friendly tool for manually annotating ECG recordings.
    % Enables precise identification of QRS onset, QRS offset, and T-wave offset
    % for calculating QRS duration (QRSd) and QT interval. Features a GUI with
    % intuitive tools for waveform labeling, slur/notch detection, and heartbeat
    % classification. Supports multiple ECG file formats and exports annotations
    % for seamless integration with AI systems or external review.
    %% Citation:
    % S. Abdel-Rahman et al., "Faster R-CNN approach for estimating global QRS duration
    % in electrocardiograms with a limited quantity of annotated data," Comput. Biol. Med.,
    % vol. 192, p. 110200, 2025. https://doi.org/10.1016/j.compbiomed.2025.110200

    methods (Access = private)

        function results = ResetQRS(comp)
            % Reset to default state
            comp.QButton.Value=0;comp.qButton.Value=0;comp.RButton.Value=0;comp.rButton.Value=0; % reset Button Status
            comp.SButton.Value=0;comp.sButton.Value=0;comp.RButton_2.Value=0;comp.rButton_2.Value=0;
            comp.SButton_2.Value=0;comp.sButton_2.Value=0;
            comp.FilterButton_3.Value=0;
            comp.UseREF1Button.Visible="on";
            comp.UseREF2Button.Visible="on";
            comp.Lamp.Color=[1,1,1];
            comp.ZoomSelectionButtom.Value=0;
            results='OK ResetQRS';
        end

        function results = ReadConfig(comp)
            % read QRS complex naming based on the status buttons
            if comp.QButton.Value==01
                Qq='Q';
            elseif comp.qButton.Value==01
                Qq='q';
            else
                Qq='';
            end

            if comp.RButton.Value==01
                Rr='R';
            elseif comp.rButton.Value==01
                Rr='r';
            else
                Rr='';
            end

            if comp.SButton.Value==01
                Ss='S';
            elseif comp.sButton.Value==01
                Ss='s';
            else
                Ss='';
            end

            if comp.RButton_2.Value==01
                Rr2='R''';
            elseif comp.rButton_2.Value==01
                Rr2= 'r''';
            else
                Rr2='';
            end

            if comp.SButton_2.Value==01
                Sr2='S''';
            elseif comp.sButton_2.Value==01
                Sr2= 's''';
            else
                Sr2='';
            end
            results=strcat(Qq,Rr,Ss,Rr2,Sr2);

            if strcmp(results,comp.Ref1QRSc.Text) || isempty( comp.Ref1QRSc.Text)
                comp.UseREF1Button.Visible="off";
            else
                comp.UseREF1Button.Visible="on";
            end

            if strcmp(results,comp.Ref2QRSc.Text) || isempty( comp.Ref2QRSc.Text)
                comp.UseREF2Button.Visible="off";
            else
                comp.UseREF2Button.Visible="on";
            end

        end


        function results = GoGreen(comp)
            % the lamb color will be give indication if there is somthing missing.
            Green=[0,1,0];
            StdBackgroundColor=[0.94,0.94,0.94];
            GoTxtGreen=0;GoLampGreen=0;
            NSOK=0;

            if ~strcmp (comp.QRSConfigTextBox.Value,'N/A' ) && ~isempty(comp.QRSConfigTextBox.Value) %*** add to check wrong config
                GoTxtGreen=1;
            end

            if comp.NSon+comp.NSoff >15 || (comp.NotchButton.Value==1 || comp.SlurButton.Value==1)
                NSOK=-1;
                if comp.NSon+comp.NSoff >15 && (comp.NotchButton.Value==1 || comp.SlurButton.Value==1)
                    NSOK=01;
                end
            end

            if GoTxtGreen==1
                comp.QRSConfigTextBox.BackgroundColor=Green;
            else
                comp.QRSConfigTextBox.BackgroundColor=StdBackgroundColor;
            end

            if GoTxtGreen==1 && NSOK>=0
                GoLampGreen=1;
            end

            if GoLampGreen==1
                comp.Lamp.Color=Green;
            else
                comp.Lamp.Color=[1-GoLampGreen,0,0];
            end

            results=[GoLampGreen,GoTxtGreen];

            % drawnow
        end
    end

    methods (Access = public)

        function results = Update(comp,METADATA)
            % update the components QRS onset and offset and Fs
            comp.BEATMETADATA=METADATA; % 
            comp.QRSon= comp.BEATMETADATA(1);
            comp.QRSoff= comp.BEATMETADATA(2);
            xline( comp.UIAxes,METADATA(1),'k:',LineWidth=1.0); % QRS ONSET and offset lines
            xline( comp.UIAxes,METADATA(2),'k:',LineWidth=1.0);
            results='OK Update';
        end

        function results = UpdateRef(comp,Ref1,Ref2,Ref1UserName,Ref1QRSc,Ref1NS,Ref2UserName,Ref2QRSc,Ref2NS)
       % update the components observer's data
            comp.Observer1Label.Text=strcat(Ref1UserName,':');
            comp.Ref1QRSc.Text=Ref1QRSc;
            comp.Ref1NSLabel.Text=Ref1NS;

            comp.Observer2Label.Text=strcat(Ref2UserName,':');
            comp.Ref2QRSc.Text=Ref2QRSc;
            comp.Ref2NSLabel.Text=Ref2NS;
            results=strcat(Ref1,Ref2);


            if isempty( comp.Ref1QRSc.Text)
                comp.UseREF1Button.Visible="off";
            else
                comp.UseREF1Button.Visible="on";

            end

            if isempty( comp.Ref2QRSc.Text)
                comp.UseREF2Button.Visible="off";
            else
                comp.UseREF2Button.Visible="on";
            end

        end

        function results = PlotQRS(comp,X,TS,LEAD,METADATA,NSReferenceLock)
           % Main function to plot the median beat and update all related meta and observers' data
            comp.BEATMETADATA=METADATA; 
            ax=comp.UIAxes; ax.reset ;
            comp.TS=TS;
            comp.PlotHandler= plot(comp.UIAxes,TS,X,'k','DisplayName','ECG',LineWidth=0.75);%hold(ax, "on");
            hold(comp.UIAxes, "on");
            title (comp.UIAxes,LEAD);
            xlabel (comp.UIAxes,'ms');ylabel(ax,'mV')
            xticks (comp.UIAxes,0:200:2000);
            yticks (comp.UIAxes,-2.5:0.5:2.5); 
            grid (comp.UIAxes,"on");
            grid (comp.UIAxes,"minor");
            comp.UIAxes.MinorGridLineWidth=1;
            comp.UIAxes.XAxis.MinorTick = 'on';
            comp.UIAxes.XAxis.MinorTickValues = 0:40:2000; comp.UIAxes.YAxis.MinorTickValues = -2.5:0.1:2.5;
            comp.UIAxes.GridColor = [ 1 0 0 ]; comp.UIAxes.LineWidth = 1; comp.UIAxes.MinorGridColor = [1 0 0];
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.UIAxes.Toolbar.Visible = 'on'; 

            comp.NSonLine= xline( comp.UIAxes,comp.NSon,'B',LineWidth=1.0);
            comp.NSoffLine= xline( comp.UIAxes,comp.NSoff,'B--',LineWidth=1.0);
            comp.RPeakLine = xline( comp.UIAxes,comp.RPeak,'G--',LineWidth=1.0);


            xline( comp.UIAxes,METADATA(1),'k:',LineWidth=1.0); % QRS ONSET and offset lines
            xline( comp.UIAxes,METADATA(2),'k:',LineWidth=1.0);
            comp.NSonLine.Value= comp.NSon;
            comp.NSoffLine.Value= comp.NSoff;
            comp.RPeakLine.Value=comp.RPeak;
            comp.QRSon= comp.BEATMETADATA(1);
            comp.QRSoff= comp.BEATMETADATA(2);


            % Plot Observers Notch or sluts if any
            YLoc=.98*comp.UIAxes.YLim;
            if NSReferenceLock(1)>0
                plot( comp.UIAxes,NSReferenceLock(1), YLoc(2) ,'VG',DisplayName='Ref1 NS',LineWidth=1) %***
            end


            if NSReferenceLock(2)>0
                plot( comp.UIAxes,NSReferenceLock(2), YLoc(2) ,'VR',DisplayName='Ref2 NS', LineWidth=1.5) %***
            end

            %%
            comp.X=X;
            if METADATA(2)>1
                comp.QRSoff= METADATA(2);
                comp.QRSon= METADATA(1);
            else
                comp.QRSoff=length(X)-1; %Temp VAlue
            end
            results='OK PlotQRS';
        end

        function results = ResetControl(comp)
           % Reset the component to the default state
            comp.NAButton.Value=1;
            comp.NotchButton.Value=0;
            comp.SlurButton.Value=0;
            comp.ZoomQRS.Value=0;
            comp.QRS_Config='N/A';

            comp.MidNotch = comp.NotchButton.Value;
            comp.MidSlur = comp.SlurButton.Value;
            ResetQRS(comp);
            comp.QRSConfigTextBox.Value=comp.QRS_Config;
            comp.QRSConfigTextBox.BackgroundColor=[0.94,0.94,0.94];
            comp.NSon=0;comp.NSoff=0;
            comp.QRSon=0;comp.QRSoff=0;
            comp.RPeak =0;

            results='OK ResetControl';
        end

        function results = AutoRPeak(comp)
            % To find r peak automatically, you can change the code to avoid using findpeaks function.
            if length(comp.X)>100
                comp.QRSoff= comp.BEATMETADATA(2);
                comp.QRSon= comp.BEATMETADATA(1);
                FsScale=1000/comp.BEATMETADATA(3);

                SearchStart=round(comp.QRSon/FsScale); if SearchStart<1,SearchStart=1;end
                if comp.QRSoff< comp.QRSon+3
                    SearchEnd=length(comp.X);
                else
                    SearchEnd=round(comp.QRSoff/FsScale);
                end
                
                % Find 1 peak which could be a candidate R Peak
                % Orginal code which replaced
                % [~,RPeakPoints]=findpeaks(comp.X(SearchStart:SearchEnd),"NPeaks",1,"SortStr","descend");
                % if ~isempty( RPeakPoints)
                %     comp.RPeak= (RPeakPoints(1)-FsScale)*FsScale+comp.QRSon;
                %     comp.RPeakLine.Value= comp.RPeak;
                %     comp.QRS_RPeak= comp.RPeak-comp.QRSon;
                %     comp.RPeakLabel.Text=num2str(comp.QRS_RPeak);
                % else
                %     comp.RPeakLabel.Text='No Wave!';
                % end
                % Replacment Code

               [PeakValue,RPeakPoints ]=max(comp.X(SearchStart:SearchEnd));
                
                if ~isempty( RPeakPoints) && PeakValue>0
                    comp.RPeak= (RPeakPoints(1)-FsScale)*FsScale+comp.QRSon;
                    comp.RPeakLine.Value= comp.RPeak;
                    comp.QRS_RPeak= comp.RPeak-comp.QRSon;
                    comp.RPeakLabel.Text=num2str(comp.QRS_RPeak);
                else
                    comp.RPeakLabel.Text='No Wave!';
                end
            end
            results='OK AutoRPeak for ALL';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: QRSPatternButtonGroup
        function QRSPatternButtonGroupSelectionChanged(comp, event)
            % to apply QRS morphology naming

            if ~strcmp( comp.QRSPatternButtonGroup.SelectedObject.Text,'>>')
                comp.QRSConfigTextBox.Value=comp.QRSPatternButtonGroup.SelectedObject.Text;
                ResetQRS(comp);
            else
                comp.QRSConfigTextBox.Value='';
                focus( comp.QRSConfigTextBox)
            end
            comp.QRS_Config=comp.QRSConfigTextBox.Value;

            GoGreen(comp);
        end

        % Button down function: QRSPatternButtonGroup
        function QRSPatternButtonGroupButtonDown(comp, event)


        end

        % Value changed function: NotchButton
        function NotchButtonValueChanged(comp, event)
            comp.MidNotch = comp.NotchButton.Value;
            comp.SlurButton.Value=0;
            comp.MidSlur = comp.SlurButton.Value;
            GoGreen(comp);

        end

        % Value changed function: SlurButton
        function SlurButtonValueChanged(comp, event)
            comp.MidSlur = comp.SlurButton.Value;
            comp.NotchButton.Value=0;
            comp.MidNotch = comp.NotchButton.Value;
            GoGreen(comp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(comp, event)
            % to select notch, slur R peak location
            coordinates = event.IntersectionPoint(1:2) ; 
            comp.QRSon= comp.BEATMETADATA(1);
            comp.QRSoff= comp.BEATMETADATA(2);

            NewX=round(coordinates(1)/2,0)*2;
            if (event.Button)==1 && ( comp.EndSel.Value==0 && comp.RPeakSel.Value==0 )
                comp.NSon =NewX;
                comp.NSonLine.Value= comp.NSon;
            elseif (event.Button)==3  || ( (event.Button)==1 && comp.EndSel.Value ==1)

                comp.NSoff =NewX;
                comp.NSoffLine.Value= comp.NSoff;
            elseif (event.Button)==2 || ( (event.Button)==1 && comp.RPeakSel.Value ==1)
                comp.RPeak =NewX;
                comp.RPeakLine.Value=comp.RPeak;
                comp.QRS_RPeak= comp.RPeak-comp.QRSon;
                comp.RPeakLabel.Text=strcat(num2str(comp.QRS_RPeak),' ms');
            end

            GoGreen(comp);

        end

        % Value changed function: QButton, RButton, RButton_2, SButton, 
        % ...and 6 other components
        function rButton_2ValueChanged(comp, event)
           
            comp.TypeQRSc.Value=1;
            if event.Value==1
                switch event.Source.Text
                    case 'q'
                        comp.QButton.Value=0;
                    case 'Q'
                        comp.qButton.Value=0;
                    case 'r'
                        comp.RButton.Value=0;
                    case 'R'
                        comp.rButton.Value=0;
                    case 's'
                        comp.SButton.Value=0;
                    case 'S'
                        comp.sButton.Value=0;
                    case 'r'''
                        comp.RButton_2.Value=0;
                    case 'R'''
                        comp.rButton_2.Value=0;
                    case 's'''
                        comp.SButton_2.Value=0;
                    case 'S'''
                        comp.sButton_2.Value=0;

                    otherwise
                        disp('Check Error')
                end
            end

            comp.QRSConfigTextBox.Value = ReadConfig(comp);
            comp.QRS_Config= comp.QRSConfigTextBox.Value;

            GoGreen(comp);

        end

        % Value changing function: QRSConfigTextBox
        function QRSConfigTextBoxValueChanging(comp, event)
            comp.TypeQRSc.Value=1;
            comp.QRS_Config= event.Value;
            GoGreen(comp);
        end

        % Value changed function: ZoomQRS
        function ZoomQRSValueChanged(comp, event)

            % zoom
            value = comp.ZoomQRS.Value;
            if comp.NSon>10;ZoomOn=comp.NSon;else;ZoomOn=comp.QRSon;end
            if comp.NSon>10;ZoomOff=comp.NSoff;else;ZoomOff=comp.QRSoff;end

            ScaleFactor1=(1-5/100); % you can change 5 to any thing
            ScaleFactor2=(1+5/100);

            if value==1
                comp.OrginalXLimit=xlim(comp.UIAxes);
                xlim(comp.UIAxes,[ZoomOn*ScaleFactor1,ZoomOff*ScaleFactor2]);
            else
                xlim(comp.UIAxes,comp.OrginalXLimit);
            end

        end

        % Button pushed function: RPeakButton
        function RPeakButtonPushed(comp, event)
           % Rpeak finder
            AutoRPeak(comp);
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            % erase selected boundries and R peak if any
            comp.NSon =0;
            comp.NSonLine.Value= comp.NSon;
            comp.NSoff =0;
            comp.NSoffLine.Value= comp.NSoff;

            comp.RPeak =0;
            comp.RPeakLine.Value=comp.RPeak;
            comp.QRS_RPeak= 0;
            comp.RPeakLabel.Text=strcat(num2str(comp.QRS_RPeak),' ms');
            GoGreen(comp)
            comp.SlurButton.Value=0; %***
            comp.NotchButton.Value=0;

        end

        % Button pushed function: UseREF1Button
        function UseREF1ButtonPushed(comp, event)
            % User observer 1 QRS complex morphology
            comp.QRSConfigTextBox.Value = comp.Ref1QRSc.Text{1,1};
            comp.QRS_Config=comp.QRSConfigTextBox.Value;
            comp.TypeQRSc.Value=1;
            GoGreen(comp);
            comp.UseREF1Button.Visible="off";

        end

        % Button pushed function: UseREF2Button
        function UseREF2ButtonPushed(comp, event)
            % User observer 2 QRS complex morphology
            comp.QRSConfigTextBox.Value = comp.Ref2QRSc.Text{1,1};
            comp.QRS_Config=comp.QRSConfigTextBox.Value;
            comp.TypeQRSc.Value=1;
            GoGreen(comp);
            comp.UseREF2Button.Visible="off";
        end

        % Button pushed function: HelpGridButton
        function HelpGridButtonPushed(comp, event)
            % This Function will  show the grid line of the dominont wave and Half of that value

            MaxX=max(abs(comp.X));
            currentLimits = axis(comp.UIAxes);
            yline( comp.UIAxes,[-MaxX,MaxX ],'C--',LineWidth=0.75)
            yline( comp.UIAxes,[-MaxX/2,MaxX/2],'C-.',LineWidth=0.5)

            axis(comp.UIAxes, currentLimits);


        end

        % Value changed function: ZoomSelectionButtom
        function ZoomSelectionButtomValueChanged(comp, event)

            value = comp.ZoomSelectionButtom.Value;
            if value==1

                % Store original limits (e.g., when the app is initialized)
                comp.OrginalXYLimit   = [comp.UIAxes.XLim;comp.UIAxes.YLim];

                zoom(comp.UIAxes , 'on')
            else
                zoom(comp.UIAxes , 'off')
                % xlim(comp.UIAxes, comp.OrginalXYLimit (1,:));
                % ylim(comp.UIAxes, comp.OrginalXYLimit (2,:));

            end

        end

        % Button pushed function: ZoomHome
        function ZoomHomeButtonPushed(comp, event)


            zoom(comp.UIAxes , 'off')
            xlim(comp.UIAxes, comp.OrginalXYLimit (1,:));
            ylim(comp.UIAxes, comp.OrginalXYLimit (2,:));

        end

        % Value changed function: StartSel
        function StartSelValueChanged(comp, event)
            value = comp.StartSel.Value;

            comp.EndSel.Value=0;
            comp.RPeakSel.Value=0;

        end

        % Value changed function: EndSel
        function EndSelValueChanged(comp, event)
            value = comp.EndSel.Value;
            comp.StartSel.Value=0;
            comp.RPeakSel.Value=0;
        end

        % Value changed function: RPeakSel
        function RPeakSelValueChanged(comp, event)
            value = comp.RPeakSel.Value;
            comp.StartSel.Value=0;
            comp.EndSel.Value=0;

        end

        % Value changed function: FilterButton_3
        function FilterButton_3ValueChanged(comp, event)
            value = comp.FilterButton_3.Value;


            if value==1
                delete(comp.PlotHandler);
                FilteredX= movmean( comp.X,comp.SmothFactor.Value); % You can change this line to employ any kind of filters.
                plot(comp.UIAxes,comp.TS,FilteredX,'R','DisplayName','ECG',LineWidth=0.65);%hold(ax, "on");
            else
                plot(comp.UIAxes,comp.TS, comp.X ,'B','DisplayName','ECG',LineWidth=0.75);%hold(ax, "on");
            end

        end

        % Value changed function: Help
        function HelpValueChanged(comp, event)
            value = comp.Help.Value;
            dlgWidth = 450;
dlgHeight = 300;
btnWidth = 150;
btnHeight = 35;
xCenter = (dlgWidth - btnWidth) / 2;

d = dialog('Position',[500 500 dlgWidth dlgHeight],'Name','CardioMark');

% Title
uicontrol('Parent',d,...
    'Style','text',...
    'FontSize',20,...
    'FontWeight','bold',...
    'HorizontalAlignment','center',...
    'Position',[25 230 400 40],...
    'String','Buttons Descriptions');

% Description for "S" Button
uicontrol('Parent',d,...
    'Style','text',...
    'FontSize',14,...
    'HorizontalAlignment','left',...
    'Position',[25 180 400 30],...
    'String','S: Start of Notch/Slur');

% Add more button descriptions here if needed
% Example:
uicontrol('Parent',d,...
    'Style','text',...
    'FontSize',14,...
    'HorizontalAlignment','left',...
    'Position',[25 150 400 30],...
    'String','E: Notch/Slur');

uicontrol('Parent',d,...
    'Style','text',...
    'FontSize',14,...
    'HorizontalAlignment','left',...
    'Position',[25 120 400 30],...
    'String','R: R-Peak Location');

% OK Button
uicontrol('Parent',d,...
    'Position',[xCenter 50 btnWidth btnHeight],...
    'FontSize',13,...
    'String','OK',...
    'Callback',@(src,event)close(d));

uiwait(d);

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            % comp.Lamp.Color=[1,1,1];

        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 440 358];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.QRSPatternButtonGroup = uibuttongroup(comp.GridLayout2);
            comp.QRSPatternButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @QRSPatternButtonGroupSelectionChanged, true);
            comp.QRSPatternButtonGroup.Title = 'QRS Pattern:';
            comp.QRSPatternButtonGroup.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @QRSPatternButtonGroupButtonDown, true);
            comp.TypeQRSc = uiradiobutton(comp.QRSPatternButtonGroup);
            comp.TypeQRSc.Tooltip = {'Type a QRS pattern or use the buttons'};
            comp.TypeQRSc.Text = '>>';
            comp.QRSConfigTextBox = uieditfield(comp.QRSPatternButtonGroup, 'text');
            comp.QRSConfigTextBox.CharacterLimits = [0 10];
            comp.QRSConfigTextBox.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @QRSConfigTextBoxValueChanging, true);
            comp.QRSConfigTextBox.Tooltip = {'Type the QRS pattern here or use the buttons below.'};
            comp.QRSConfigTextBox.Value = 'N/A';
            comp.NAButton = uiradiobutton(comp.QRSPatternButtonGroup);
            comp.NAButton.Text = 'N/A';
            comp.NAButton.Value = true;
            comp.QButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.QButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.QButton.Text = 'Q';
            comp.RButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.RButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.RButton.Text = 'R';
            comp.SButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.SButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.SButton.Text = 'S';
            comp.RButton_2 = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.RButton_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.RButton_2.Text = 'R''';
            comp.qButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.qButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.qButton.Text = 'q';
            comp.rButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.rButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.rButton.Text = 'r';
            comp.sButton = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.sButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.sButton.Text = 's';
            comp.rButton_2 = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.rButton_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.rButton_2.Text = 'r''';
            comp.sButton_2 = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.sButton_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.sButton_2.Text = 's''';
            comp.SButton_2 = uibutton(comp.QRSPatternButtonGroup, 'state');
            comp.SButton_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rButton_2ValueChanged, true);
            comp.SButton_2.Text = 'S''';
            comp.FragmentedButton = uiradiobutton(comp.QRSPatternButtonGroup);
            comp.FragmentedButton.Tooltip = {'Fragmented Pattern'};
            comp.FragmentedButton.Text = 'Fragmented';
            comp.Panel = uipanel(comp.GridLayout2);
            comp.NotchButton = uibutton(comp.Panel, 'state');
            comp.NotchButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NotchButtonValueChanged, true);
            comp.NotchButton.Tooltip = {'Label as Notch'};
            comp.NotchButton.Text = 'Notch';
            comp.SlurButton = uibutton(comp.Panel, 'state');
            comp.SlurButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SlurButtonValueChanged, true);
            comp.SlurButton.Tooltip = {'Label as Slur'};
            comp.SlurButton.Text = 'Slur';
            comp.RPeakButton = uibutton(comp.Panel, 'push');
            comp.RPeakButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RPeakButtonPushed, true);
            comp.RPeakButton.Tooltip = {'Auto R Peak Finder'};
            comp.RPeakButton.Text = 'R-Peak';
            comp.RPeakLabel = uilabel(comp.Panel);
            comp.RPeakLabel.Text = 'xx ms';
            comp.ObserversFeedbackLabel = uilabel(comp.Panel);
            comp.ObserversFeedbackLabel.Text = 'Observers Feedback:';
            comp.Observer1Label = uilabel(comp.Panel);
            comp.Observer1Label.Text = 'Observer 1:';
            comp.Ref1QRSc = uilabel(comp.Panel);
            comp.Ref1QRSc.Text = 'QRSc';
            comp.Ref1NSLabel = uilabel(comp.Panel);
            comp.Ref1NSLabel.Text = 'N/S';
            comp.UseREF1Button = uibutton(comp.Panel, 'push');
            comp.UseREF1Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UseREF1ButtonPushed, true);
            comp.UseREF1Button.Icon = fullfile(pathToMLAPP, 'resources', 'usethis.jpg');
            comp.UseREF1Button.Tooltip = {'Press to apply the observer’s QRS pattern.'};
            comp.UseREF1Button.Text = '';
            comp.Observer2Label = uilabel(comp.Panel);
            comp.Observer2Label.Text = 'Observer 2:';
            comp.Ref2NSLabel = uilabel(comp.Panel);
            comp.Ref2NSLabel.Text = 'N/S';
            comp.UseREF2Button = uibutton(comp.Panel, 'push');
            comp.UseREF2Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UseREF2ButtonPushed, true);
            comp.UseREF2Button.Icon = fullfile(pathToMLAPP, 'resources', 'usethis.jpg');
            comp.UseREF2Button.Tooltip = {'Press to apply the observer’s QRS pattern.'};
            comp.UseREF2Button.Text = '';
            comp.Ref2QRSc = uilabel(comp.Panel);
            comp.Ref2QRSc.Text = 'QRSc';
            comp.Button = uibutton(comp.Panel, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, 'resources', 'Ereaser.jpg');
            comp.Button.Tooltip = {'Remove the Notch/slur and R-Peak selection lines.'};
            comp.Button.Text = '';
            comp.Panel_2 = uipanel(comp.GridLayout2);
            comp.SmothFactor = uieditfield(comp.Panel_2, 'numeric');
            comp.SmothFactor.Limits = [1 25];
            comp.SmothFactor.ValueDisplayFormat = '%.0f';
            comp.SmothFactor.Tooltip = {'Smoothing factor'};
            comp.SmothFactor.Value = 5;
            comp.FilterButton_3 = uibutton(comp.Panel_2, 'state');
            comp.FilterButton_3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @FilterButton_3ValueChanged, true);
            comp.FilterButton_3.Tooltip = {'Apply Filter'};
            comp.FilterButton_3.Icon = fullfile(pathToMLAPP, 'resources', 'Filter.jpg');
            comp.FilterButton_3.Text = '';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.GridLayout3);
            title(comp.UIAxes, 'QRS')
            comp.UIAxes.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @UIAxesButtonDown, true);
            comp.ToolBoxPanel = uipanel(comp.GridLayout3);
            comp.ZoomQRS = uibutton(comp.ToolBoxPanel, 'state');
            comp.ZoomQRS.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ZoomQRSValueChanged, true);
            comp.ZoomQRS.Tooltip = {'Enlarge the section between QRS onset and QRS offset'};
            comp.ZoomQRS.Icon = fullfile(pathToMLAPP, 'resources', 'OIP.jpg');
            comp.ZoomQRS.Text = '';
            comp.Lamp = uilamp(comp.ToolBoxPanel);
            comp.Lamp.Tooltip = {'Good to go indicator'; 'Green: OK'; 'Other: you have missing input'};
            comp.ZoomSelectionButtom = uibutton(comp.ToolBoxPanel, 'state');
            comp.ZoomSelectionButtom.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ZoomSelectionButtomValueChanged, true);
            comp.ZoomSelectionButtom.Tooltip = {'Zoom to Selection'};
            comp.ZoomSelectionButtom.Icon = fullfile(pathToMLAPP, 'resources', 'zoom-select.png');
            comp.ZoomSelectionButtom.Text = '';
            comp.ZoomHome = uibutton(comp.ToolBoxPanel, 'push');
            comp.ZoomHome.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomHomeButtonPushed, true);
            comp.ZoomHome.Icon = fullfile(pathToMLAPP, 'resources', 'home.svg');
            comp.ZoomHome.Tooltip = {'Zoom All'};
            comp.ZoomHome.Text = '';
            comp.StartSel = uibutton(comp.ToolBoxPanel, 'state');
            comp.StartSel.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StartSelValueChanged, true);
            comp.StartSel.Tooltip = {'Start of Notch/Slur'};
            comp.StartSel.Text = 'S';
            comp.EndSel = uibutton(comp.ToolBoxPanel, 'state');
            comp.EndSel.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EndSelValueChanged, true);
            comp.EndSel.Tooltip = {'End of Notch/Slur'};
            comp.EndSel.Text = 'E';
            comp.RPeakSel = uibutton(comp.ToolBoxPanel, 'state');
            comp.RPeakSel.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RPeakSelValueChanged, true);
            comp.RPeakSel.Tooltip = {'R-Peak Location'};
            comp.RPeakSel.Text = 'R';
            comp.HelpGridButton = uibutton(comp.ToolBoxPanel, 'push');
            comp.HelpGridButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @HelpGridButtonPushed, true);
            comp.HelpGridButton.Icon = fullfile(pathToMLAPP, 'resources', 'Grid.jpg');
            comp.HelpGridButton.Tooltip = {'Display the grid to assist in determining whether the wave is dominant, following the 50% rule.'};
            comp.HelpGridButton.Text = '';
            comp.Help = uibutton(comp.ToolBoxPanel, 'state');
            comp.Help.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @HelpValueChanged, true);
            comp.Help.Tooltip = {'Overview of Button Functions'};
            comp.Help.Text = '?';
        end
    end
end